classdef CometAnalyser_GUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                        matlab.ui.Figure
        Menu_File                      matlab.ui.container.Menu
        Menu_File_OpenS                matlab.ui.container.Menu
        Menu_File_OpenF                matlab.ui.container.Menu
        Menu_File_LoadData             matlab.ui.container.Menu
        Menu_File_SaveData             matlab.ui.container.Menu
        RemoveImageMenu_2              matlab.ui.container.Menu
        BrightnessContrastMenu         matlab.ui.container.Menu
        OptionsMenu                    matlab.ui.container.Menu
        Segmentation                   matlab.ui.container.Menu
        ExportAnnotationsMenu          matlab.ui.container.Menu
        TrainNewModelMenu              matlab.ui.container.Menu
        ImageSegmentationMenu          matlab.ui.container.Menu
        DatasetSegmentationMenu        matlab.ui.container.Menu
        ClearAnnotationsMenu           matlab.ui.container.Menu
        ClearSegmentationsMenu         matlab.ui.container.Menu
        SavesegmentationstoUnclassifiedclassMenu  matlab.ui.container.Menu
        OptionsMenu_Segmentation       matlab.ui.container.Menu
        Menu_Analysis                  matlab.ui.container.Menu
        Menu_Analysis_ExpMeasurements  matlab.ui.container.Menu
        Menu_Analysis_ExpSnapshots     matlab.ui.container.Menu
        ClassificationMenu             matlab.ui.container.Menu
        TrainClassifierMenu            matlab.ui.container.Menu
        ExportClassifierMenu           matlab.ui.container.Menu
        PredictionMenu                 matlab.ui.container.Menu
        ClassificationMenu_2           matlab.ui.container.Menu
        ClassificationTrainingOptionsMenu  matlab.ui.container.Menu
        Menu_Help                      matlab.ui.container.Menu
        Menu_Help_About                matlab.ui.container.Menu
        DocumentationMenu              matlab.ui.container.Menu
        Panel                          matlab.ui.container.Panel
        axes1                          matlab.ui.control.UIAxes
        but_ShowOriImg                 matlab.ui.control.Button
        uipanel1                       matlab.ui.container.Panel
        scope                          matlab.ui.control.Image
        Class_Remove_Button            matlab.ui.control.Button
        Class_Create_Button            matlab.ui.control.Button
        ClassBrowserButton             matlab.ui.control.Button
        slider_head_Dilation           matlab.ui.control.Slider
        slider_head_threshold          matlab.ui.control.Slider
        slider_comet_Dilation          matlab.ui.control.Slider
        slider_comet_threshold         matlab.ui.control.Slider
        pop_thresholdHead              matlab.ui.control.DropDown
        text18                         matlab.ui.control.Label
        check_CometFitFreehand         matlab.ui.control.CheckBox
        text17                         matlab.ui.control.Label
        text_DilationHead              matlab.ui.control.Button
        but_diHead_plus                matlab.ui.control.Button
        but_diHead_less                matlab.ui.control.Button
        text_ThresholdHead             matlab.ui.control.Button
        but_thHead_plus                matlab.ui.control.Button
        but_thHead_less                matlab.ui.control.Button
        text14                         matlab.ui.control.Label
        text13                         matlab.ui.control.Label
        text12                         matlab.ui.control.Label
        text11                         matlab.ui.control.Label
        text_Dilation                  matlab.ui.control.Button
        text_Threshold                 matlab.ui.control.Button
        pop_threshold                  matlab.ui.control.DropDown
        text8                          matlab.ui.control.Label
        pop_tail                       matlab.ui.control.DropDown
        but_SelectComet                matlab.ui.control.Button
        but_di_plus                    matlab.ui.control.Button
        but_di_less                    matlab.ui.control.Button
        text7                          matlab.ui.control.Label
        but_th_plus                    matlab.ui.control.Button
        but_th_less                    matlab.ui.control.Button
        text6                          matlab.ui.control.Label
        pop_class                      matlab.ui.control.DropDown
        but_CometRemove                matlab.ui.control.Button
        but_CometAdd                   matlab.ui.control.Button
        text5                          matlab.ui.control.Label
        text_Name                      matlab.ui.control.Label
        text_Num                       matlab.ui.control.Label
        but_Right                      matlab.ui.control.Button
        but_Left                       matlab.ui.control.Button
    end


    properties (Access = public)
        comet_handles % Stores all commonly shared necessary data here
        selectedComet % Description
        CometIcon % Description
        imDatatipText = []; % Description
        classBrowser_handle
        classCreationSuccessor; % Description
        metaDataPath % Description
        classificationMethod = 'Decision tree';
        classificationModelPath % Description
        ProjectName % Description
        SegmentationBool  = 0; % state of automatically segmented data
        tempFig;
        imContrastHandles; % Description
        newMaxIntensity = 255; % Description
        newMinIntensity = 0; % Description
        version = 1.01; % Description
        delay = [];
    end

    properties (Access = private)
        calculating = 0 % Description
        classNameInputHandle = [] % Description
        ClassifierModel % Description
        classificationTrainingOptions_handle = [] % Description
        segmentationTrainingOptions_handle = [] % Description
    end

    methods (Access = private)

        function appTextDlg(app, text, title, type)
            if ishandle(app.comet_handles.h_GUIhelpA); close(app.comet_handles.h_GUIhelpA); end
            app.comet_handles.h_GUIhelpA = msgbox(sprintf('%s\n',text{:}),title,type); % type: 'help', 'warn', 'error', 'none'
            %To delete the button OK in the lower part of the window
            child = get(app.comet_handles.h_GUIhelpA,'Children');
            if isnumeric(child)
                delete(child(end))
            else
                delete(child(1))
            end
            pause(2*eps);
            InterfaceObj=findobj(app.figure1,'Enable','on');
            set(InterfaceObj,'Enable','off');
            waitfor(app.comet_handles.h_GUIhelpA);
            set(InterfaceObj,'Enable','on');
        end

        function reset_Comet_Handles(app)
            app.comet_handles.version = app.version;
            app.comet_handles.uniqueIdentifier = str2double(erase(datestr(now,30),'T'));
            app.comet_handles.h_GUIhelpA = [];
            app.comet_handles.PathInputFolderOriginal = [];
            app.comet_handles.FluorescenceImages = 0;
            app.comet_handles.ImageFormat = [];
            app.comet_handles.ImageSize = [];
            app.comet_handles.Imgs_Ori = [];
            app.comet_handles.Imgs_Stretched = [];
            app.comet_handles.NumImages = [];
            app.comet_handles.dirList = [];
            app.comet_handles.ImgsNames = [];
            app.comet_handles.IndImgShown = [];
            app.comet_handles.flag_CurrentCometType = 1;
            app.comet_handles.CurrentCometHead_YrowXcol = [];
            app.comet_handles.flag_ThresholdMode = 1; %1=Otsu, 2=Triangle, 3=average Otsu and Triangle.
            app.comet_handles.flag_ThresholdHeadMode = 1; %1=Otsu, 2=Triangle, 3=average Otsu and Triangle.
            app.comet_handles.CometThAddFactor = 0;
            app.comet_handles.CometSizeDiskDilation = 0;
            app.comet_handles.HeadThAddFactor = 0;
            app.comet_handles.HeadSizeDiskDilation = 0;
            app.comet_handles.ROIshown = 0;
            app.comet_handles.ROIori = [];
            app.comet_handles.ROIoriFiltered = [];
            app.comet_handles.ROIsegm = [];
            app.comet_handles.MaskComet = [];
            app.comet_handles.MaskHead = [];
            app.comet_handles.ROI_ULCyx_DRCyx = [];
            app.comet_handles.flag_CometFitFreehand = 0;
            app.comet_handles.ClassID = 0;
            app.comet_handles.SelectACometOngoing = 0;
            app.comet_handles.LoadImagesOngoing = 0;
            app.comet_handles.IntensityMinValStretched = 0;
            app.comet_handles.IntensityMaxValStretched = 255;
            app.comet_handles.IntensityMinValOrig = 0;
            app.comet_handles.IntensityMaxValOrig = 255;
            app.comet_handles.ROIminNumPixels = 25*25;
            app.comet_handles.FlagNewComets = 0;
            app.comet_handles.AutoSaveEveryNComets = 10;
            app.comet_handles.Classes = [];
            app.comet_handles.ClassCounter = 0;
            app.comet_handles.segmentationOptions.modelPath = 'Default';
            app.comet_handles.segmentationOptions.InitialLearnRate = '0.003';
            app.comet_handles.segmentationOptions.LearnRateDropPeriod = '5';
            app.comet_handles.segmentationOptions.LearnRateDropFactor = '0.5';
            app.comet_handles.segmentationOptions.MaxEpoch = '50';
            app.comet_handles.segmentationOptions.MiniBatchSize = '1';
            app.comet_handles.segmentationOptions.ValidationPatience = '10';
            app.comet_handles.segmentationOptions.Plot = 'none';
            app.comet_handles.segmentationOptions.ShuffleData = 'every-epoch';
            app.comet_handles.segmentationOptions.ExecutionEnvironment = 'auto';
            app.comet_handles.segmentationOptions.L2Regularization = '0.005';
            app.comet_handles.segmentationOptions.Momentum = '0.9';
        end

        function resetComponents(app)
            app.pop_class.Items = {'~No Class~'};
            app.pop_class.Value = '~No Class~';
            app.pop_tail.Value = app.pop_tail.Items(1);
            app.pop_threshold.Value = app.pop_threshold.Items(1);
            app.pop_thresholdHead.Value = app.pop_thresholdHead.Items(1);
            app.text_Dilation.Text = '0';
            app.text_DilationHead.Text = '0';
            app.text_Threshold.Text = '0';
            app.text_ThresholdHead.Text = '0';
            app.slider_comet_Dilation.Value = 0;
            app.slider_comet_threshold.Value = 0.5;
            app.slider_head_Dilation.Value = 0;
            app.slider_head_threshold.Value = 0.5;
            app.check_CometFitFreehand.Value = 0;
        end
    end

    methods (Access = public)

        function setMaxIntensity(app,newMax)
            app.newMaxIntensity = str2double(newMax);
        end

        function setMinIntensity(app,newMin)
            app.newMinIntensity = str2double(newMin);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function CometAnalyser_GUI_OpeningFcn(app, varargin)
            set(app.figure1, 'Name', 'CometAnalyser');
            app.CometIcon = imread('Logo_withCT_BK255.png');

            app.scope.ImageSource = app.CometIcon;
            app.selectedComet = [];
            reset_Comet_Handles(app)
            app.classBrowser_handle =[];
            app.axes1.Toolbar.Visible = "off";
            app.axes1.XAxis.Visible = 'off';
            app.axes1.YAxis.Visible = 'off';
            if ~isdeployed
                addpath(genpath(pwd))
            end
        end

        % Menu selected function: Menu_Analysis_ExpMeasurements
        function Menu_Analysis_ExpMeasurements_Callback(app, event)
            if ~isempty(app.comet_handles.IndImgShown) && app.comet_handles.IndImgShown && ~app.calculating && ~app.comet_handles.SelectACometOngoing
                app.calculating = 1;

                if ishandle(app.comet_handles.h_GUIhelpA); close(app.comet_handles.h_GUIhelpA); end

                Imgs_Stretched  = app.comet_handles.Imgs_Stretched;
                Imgs_Ori  = app.comet_handles.Imgs_Ori;
                ImgsNames       = app.comet_handles.ImgsNames;

                if any(Imgs_Stretched(:,:,2,:)==255,'all')
                    maskLayer = Imgs_Stretched(:,:,2,:);
                    headLayer = Imgs_Stretched(:,:,3,:);
                    maskLayer(maskLayer==255) = 0;
                    headLayer(headLayer==255) = 0;
                    Imgs_Stretched(:,:,2,:) = maskLayer;
                    Imgs_Stretched(:,:,3,:) = headLayer;
                end

                if any(Imgs_Stretched(:,:,2:3,:),'all')

                    % Build output folders
                    selpath = uigetdir;
                    if isempty(selpath) || ~ischar(selpath)
                        app.calculating = 0;
                        errorString = {'Wrong path.'};
                        appTextDlg(app, errorString, 'Error', 'error')
                        return
                    end
                    selectedFolder = dir(fullfile(selpath));
                    if size(selectedFolder,1) < 3
                        mkdir(fullfile(selpath,'LabeledFigures'));
                    else
                        app.calculating = 0;
                        warnString = {'Process terminated.';
                                      'The selected folder is not empty.';
                                      'Please select an empty folder!'};
                        appTextDlg(app, warnString, 'Warning', 'error')
                        return
                    end

                    a = app.comet_handles.IntensityMinValStretched;
                    b = app.comet_handles.IntensityMaxValStretched;
                    c = app.comet_handles.IntensityMinValOrig;
                    d = app.comet_handles.IntensityMaxValOrig;
                    Intensity_MinMax_StretchedOrig = [a, b, c, d];

                    classNames = fieldnames(app.comet_handles.Classes);

                    n = size(Imgs_Stretched,4);
                    % Find the comet
                    wb = waitbar(0,'Calculating measurements. Please wait...');
                    for i = 1:n
                        InterfaceObj=findobj(app.figure1,'Enable','on');
                        set(InterfaceObj,'Enable','off');
                        cometIDs_ofImagei = setdiff(unique(Imgs_Stretched(:,:,2,i)),0);
                        for j = 1:numel(cometIDs_ofImagei)
                            classIdx = [];
                            for k = 1:numel(classNames)
                                imFileter = strcmp({app.comet_handles.Classes.(classNames{k}).Members.ImName},ImgsNames{i});
                                cometIDFilter = [app.comet_handles.Classes.(classNames{k}).Members.cometID] == cometIDs_ofImagei(j);
                                if any(imFileter & cometIDFilter)
                                    classIdx = k;
                                    break
                                end
                            end

                            if ~isempty(classIdx)
                                selectedName = classNames{classIdx};
                            else % Something went wrong, no matching data in class
                                selectedName = '#Error';
                            end

                            cometMaskj = Imgs_Stretched(:,:,2,i) == cometIDs_ofImagei(j);
                            centroid = calcLargestInnerTriangleCentorid(cometMaskj);

                            [xCoor,yCoor] = find(cometMaskj);
                            xMin = min(xCoor);
                            xMax = max(xCoor);
                            yMin = min(yCoor);
                            yMax = max(yCoor);

                            ULC = [xMin, yMin];
                            DRC = [xMax, yMax];
                            ROIw = yMax-yMin+1;
                            ROIh = xMax-xMin+1;

                            Imgi_Orig_small = Imgs_Ori(ULC(1):DRC(1),ULC(2):DRC(2),1,i); % Original Images
                            Imgi_Comet_small = Imgs_Stretched(ULC(1):DRC(1),ULC(2):DRC(2),2,i); % Comet mask layer
                            Imgi_Head_small = Imgs_Stretched(ULC(1):DRC(1),ULC(2):DRC(2),3,i); % Head mask layer
                            Imgi_Tail_small = Imgi_Comet_small - Imgi_Head_small;

                            sizezeropad = max([ROIh, ROIw])+2;
                            Maski_Cometj_zeropad = false(ROIh+2*sizezeropad, ROIw+2*sizezeropad);
                            Maski_Headj_zeropad  = false(ROIh+2*sizezeropad, ROIw+2*sizezeropad);
                            Maski_Tailj_zeropad  = false(ROIh+2*sizezeropad, ROIw+2*sizezeropad);
                            Maski_Origj_zeropad  = zeros(ROIh+2*sizezeropad, ROIw+2*sizezeropad);
                            Maski_Cometj_zeropad(sizezeropad+1:end-sizezeropad, sizezeropad+1:end-sizezeropad) = Imgi_Comet_small;
                            Maski_Headj_zeropad(sizezeropad+1:end-sizezeropad, sizezeropad+1:end-sizezeropad) = Imgi_Head_small;
                            Maski_Tailj_zeropad(sizezeropad+1:end-sizezeropad, sizezeropad+1:end-sizezeropad) = Imgi_Tail_small;
                            Maski_Origj_zeropad(sizezeropad+1:end-sizezeropad, sizezeropad+1:end-sizezeropad) = Imgi_Orig_small;

                            % Compute parameters
                            Tableij = computeFeatures(Maski_Origj_zeropad, Maski_Cometj_zeropad, Maski_Tailj_zeropad, Maski_Headj_zeropad,...
                                selectedName, ImgsNames{i}, Intensity_MinMax_StretchedOrig, j, ULC, DRC, centroid);

                            if ~exist('TableAll', 'var')
                                for fn = fieldnames(Tableij)'
                                    TableAll.(fn{1,1}) = Tableij.(fn{1,1});
                                end
                            else
                                TableAll = [TableAll, Tableij]; %#ok<AGROW>
                            end
                        end

                        Imgi_Class_BW_withNumbers_ch1 = Imgs_Stretched(:,:,1,i);
                        Imgi_Class_BW_withNumbers_ch2 = Imgs_Stretched(:,:,1,i);
                        Imgi_Class_BW_withNumbers_ch3 = Imgs_Stretched(:,:,1,i);
                        Imgi_Class_BW_withNumbers_ch2(logical(Imgs_Stretched(:,:,2,i))) = 150;
                        Imgi_Class_BW_withNumbers(:,:,1) = Imgi_Class_BW_withNumbers_ch1;
                        Imgi_Class_BW_withNumbers(:,:,2) = Imgi_Class_BW_withNumbers_ch2;
                        Imgi_Class_BW_withNumbers(:,:,3) = Imgi_Class_BW_withNumbers_ch3;

                        % Image labeling
%                         for u = 1:numel(cometIDs_ofImagei)
%                             BWimageu = Imgs_Stretched(:,:,2,i) == cometIDs_ofImagei(u);
%                             CentroidCoordinates = struct2cell(regionprops(BWimageu,'Centroid'));
%                             Imgi_Class_BW_withNumbers = AddTextToImage(Imgi_Class_BW_withNumbers,num2str(u),ceil([CentroidCoordinates{1,1}(2)-20, CentroidCoordinates{1,1}(1)-10]),[255, 255, 255],'Arial',42);
%                             Imgi_Class_BW_withNumbers = AddTextToImage(Imgi_Class_BW_withNumbers,num2str(u),ceil([CentroidCoordinates{1,1}(2)-20, CentroidCoordinates{1,1}(1)-10]),[0, 0, 0],'Arial',38);
%                         end
                        imFilter_ALL = strcmp({TableAll.ImageName},ImgsNames{i});
                        position = zeros(sum(imFilter_ALL),2);
                        position(:,1) = [TableAll(imFilter_ALL).centroidY];
                        position(:,2) = [TableAll(imFilter_ALL).centroidX];
                        cometID_text_str = cellstr(num2str([TableAll(imFilter_ALL).CometID]'));

                        Imgi_Class_BW_withNumbers = insertText(Imgi_Class_BW_withNumbers,position,cometID_text_str,'FontSize',32,'BoxColor',...
                                         'black','BoxOpacity',0,'TextColor','red','AnchorPoint','Center');
                        imwrite(Imgi_Class_BW_withNumbers, fullfile(selpath,'LabeledFigures', ImgsNames{i}));
                        wb = waitbar(i/n,wb,'Calculating measurements. Please wait...');
                    end
                    wb = waitbar(n*0.95,wb,'Saving measurement file. Please wait...');
                    
                    if ishandle(app.comet_handles.h_GUIhelpA); close(app.comet_handles.h_GUIhelpA); end

                    if exist('TableAll', 'var')
                        t = struct2table(TableAll);
                        identifierRow = t(1,:);
                        identifierRow.ImageName = {'Measurement Identifier:'};
                        identifierRow.CometID = app.comet_handles.uniqueIdentifier;
                        for fin = 3:size(identifierRow,2)
                            identifierRow.(identifierRow.Properties.VariableNames{fin}) = NaN;
                        end
                        t = [identifierRow;t];
                        mesurementFileName = ['dataExtracted_',datestr(now,30),'.csv'];
                        writetable(t,fullfile(selpath,mesurementFileName));
                        app.metaDataPath = fullfile(selpath,mesurementFileName);
                    else
                        % Exporting data message
                        errorString = {'No segmented comets.'};
                        appTextDlg(app, errorString, 'Message', 'warn')
                    end
                    if ishandle(wb), close(wb), end
                    InterfaceObj=findobj(app.figure1,'Enable','off');
                    set(InterfaceObj,'Enable','on');
                else
                    errorString = {'No comet in the classes.'};
                    appTextDlg(app, errorString, 'Message', 'warn')
                end
                app.calculating = 0;
            end
        end

        % Menu selected function: Menu_Analysis_ExpSnapshots
        function Menu_Analysis_ExpSnapshots_Callback(app, event)
            if ishandle(app.comet_handles.h_GUIhelpA); close(app.comet_handles.h_GUIhelpA); end
            if any(app.comet_handles.Imgs_Stretched(:,:,2,:),'all')
                Imgs_Stretched  = app.comet_handles.Imgs_Stretched;

                selpath = uigetdir;
                if isfolder(selpath)
                    if exist(fullfile(selpath,'CometSnapshots'),"dir")
                        answer = questdlg('The selected folder is not empty. Would you like to continue?','Not empty folder','Continue','Cancel','Cancel');
                        if strcmp(answer,'Continue')
                            mkdir(fullfile(selpath,'CometSnapshots'));
                        end
                    else
                        mkdir(fullfile(selpath,'CometSnapshots'));
                    end
                    classNames = fieldnames(app.comet_handles.Classes);
                    for j = 1:app.comet_handles.ClassCounter
                        mkdir(fullfile(selpath,'CometSnapshots', classNames{j}));
                    end

                    % Save snapshot
                    for cl = 1:numel(classNames)
                        numimgs = size(app.comet_handles.Classes.(classNames{cl}).Members,2);
                        for i = 1:numimgs
                            imID = strcmp(app.comet_handles.Classes.(classNames{cl}).Members(i).ImName,app.comet_handles.ImgsNames);
                            cometID = app.comet_handles.Classes.(classNames{cl}).Members(i).cometID;
                            [xCoor,yCoor] = find(app.comet_handles.Imgs_Stretched(:,:,2,imID) == cometID);
                            xMin = min(xCoor);
                            xMax = max(xCoor);
                            yMin = min(yCoor);
                            yMax = max(yCoor);
                            snapshot = Imgs_Stretched(xMin:xMax, yMin:yMax,1,imID);
                            imwrite(snapshot, fullfile(selpath,'CometSnapshots', classNames{cl}, ['Comet_Image_' num2str(i) '_' classNames{cl} '.tiff']));
                        end
                    end
                else
                    warnString = {'Invalid path.'};
                    appTextDlg(app, warnString, 'Message', 'warn')
                end
            end
        end

        % Menu selected function: Menu_File_LoadData
        function Menu_File_LoadData_Callback(app, event)
            if ishandle(app.comet_handles.h_GUIhelpA); close(app.comet_handles.h_GUIhelpA); end
            bool = 0;
            answer = 'No';
            if ~isempty(app.comet_handles.IndImgShown)
                answer = questdlg('Unsaved changes will be lost. Would you like to continue?','Load project','Yes','Cancel','Cancel');
            else
                bool = 1;
            end
            if strcmp(answer, 'Yes') || bool
                reset_Comet_Handles(app)
                resetComponents(app);

                [fileName, PathInputFolder] = uigetfile({'*.mat;'}, 'Select a previously saved datased (.mat)', 'MultiSelect', 'off');
                if ~isempty(PathInputFolder) && all(PathInputFolder~=0)
                    try
                        VariableOpen = load([PathInputFolder, fileName]);
                        if ~isfield(VariableOpen,'comet_handles') || ~isfield(VariableOpen.comet_handles,'version') || VariableOpen.comet_handles.version ~= app.version
                            loadedFieldNames = fieldnames(VariableOpen);
                            if length(loadedFieldNames) == 1
                                VariableOpen = VariableOpen.(loadedFieldNames{1});
                                loadedFieldNames = fieldnames(VariableOpen);
                            end
                            for i = 1:numel(loadedFieldNames)
                                % if any(strcmp(loadedFieldNames{i},cometHandleFieldNames))
                                app.comet_handles.(loadedFieldNames{i}) = VariableOpen.(loadedFieldNames{i});
                                %                                 else
                                %                                     waitfor(warndlg('The following field of the loaded project does not match any of the fields in this version of the software.',"Missing field"))
                                %                                     answer = listdlg('PromptString',{'Please select the matching field for:',loadedFieldNames{i},''},...
                                %                                         'SelectionMode','single','ListString',cometHandleFieldNames);
                                %
                                %                                     if ~isempty(answer) && isnumeric(answer)
                                %                                         app.comet_handles.(cometHandleFieldNames{answer}) = VariableOpen.(loadedFieldNames{i});
                                %                                     else
                                %                                         app.comet_handles.loadedFieldNames{i} = VariableOpen.loadedFieldNames{i};
                                %                                     end
                                %                                 end
                            end

                            [bool, comet_handles] = checkMissingValues4comet_handles(app.comet_handles);  %#ok
                            if bool
                                app.comet_handles = comet_handles; %#ok
                            else
                                errorString = {'Failed to load the project. The project file is corrupted or outdated!'};
                                appTextDlg(app, errorString, 'Message', 'warn')
                                return
                            end
                        else
                            app.comet_handles = VariableOpen.comet_handles;
                        end

                        validPath = isfolder(app.comet_handles.PathInputFolderOriginal);

                        while ~validPath
                            app.comet_handles.PathInputFolderOriginal = uigetdir([],'Select original image folder');
                            validPath = isfolder(app.comet_handles.PathInputFolderOriginal);
                        end

                        app.scope.ImageSource = app.CometIcon;
                        app.selectedComet = [];
                        set(app.text_Num,'Text', ['Image: ' num2str(app.comet_handles.IndImgShown) '/' num2str(app.comet_handles.NumImages)]);
                        set(app.text_Name,'Text', ['Image: ' char(app.comet_handles.ImgsNames{app.comet_handles.IndImgShown})]);
                        set(app.pop_tail, 'Value', app.pop_tail.Items(app.comet_handles.flag_CurrentCometType));
                        imshow(composeImage(app.comet_handles.Imgs_Stretched(:,:,:,app.comet_handles.IndImgShown)), [], 'Parent', app.axes1);
                        app.axes1.XAxis.Visible = 'off';
                        app.axes1.YAxis.Visible = 'off';
                        app.axes1.XTickLabel = [];
                        app.axes1.YTickLabel = [];
                        app.axes1.BackgroundColor = [0 0 0];
                        app.axes1.Toolbar.Visible = "off";
                        app.axes1.XLim = [0, app.comet_handles.ImageSize(2)];
                        app.axes1.YLim = [0, app.comet_handles.ImageSize(1)];
                        drawnow
                        pause(.1)
                        app.ProjectName = fileName;

                        if isempty(app.comet_handles.Classes)
                            [bool, message] = createUnclassifiedClassForGreens(app);
                            if bool == 0
                                appTextDlg(app, message, 'Message', 'warn')
                                return
                            end
                        else
                            classNames = fieldnames(app.comet_handles.Classes);
                            app.pop_class.Items = classNames;
                            pop_class_Callback(app)
                        end
                        app.comet_handles.version = app.version;

                    catch
                        try close(BarWaitWindows); catch; end
                        errorString = {'Failed to load the project. The project file is corrupted or outdated!'};
                        appTextDlg(app, errorString, 'Message', 'warn')
                        reset_Comet_Handles(app)
                        resetComponents(app)
                        app.scope.ImageSource = app.CometIcon;
                        app.selectedComet = [];
                        delete(app.axes1.Children)
                        set(app.text_Num,'Text', 'Image number');
                        set(app.text_Name,'Text', 'Image name');
                    end
                end
            end
        end

        % Menu selected function: Menu_File_OpenF
        function Menu_File_OpenF_Callback(app, event)
            app.comet_handles.FluorescenceImages = 1;
            Menu_File_OpenS_Callback(app);
        end

        % Menu selected function: Menu_File_OpenS
        function Menu_File_OpenS_Callback(app, event)
            if ishandle(app.comet_handles.h_GUIhelpA); close(app.comet_handles.h_GUIhelpA); end


            app.scope.ImageSource = app.CometIcon;
            app.selectedComet = [];

            if app.comet_handles.LoadImagesOngoing == 1
                return
            end

            [ImageName, PathInputFolder] = uigetfile({'*.tif;*.tiff;*.bmp;*.png;*.jpg;'}, 'Select in the input folder one of the images to be processed', 'MultiSelect', 'off', app.comet_handles.PathInputFolderOriginal);
            if ~isempty(PathInputFolder) && all(PathInputFolder~=0)
                app.comet_handles.LoadImagesOngoing = 1;
                if app.comet_handles.FluorescenceImages == 0
                    errorString = {'Images will be displayed with black background.'};
                    appTextDlg(app, errorString, 'Message', 'help')
                end

                try
                    % Read Path, name, Format
                    splitName = split(ImageName, '.');
                    ImageFormat = splitName{end};

                    % Check if the input images are gray-level 8-bit images
                    dirList = dir([PathInputFolder, '*.' ImageFormat]);
                    ImgsNames = {dirList(:).name}';
                    NumImages = length(dirList(:));
                    imInfo = imfinfo(fullfile(PathInputFolder, ImageName));
                    Imgs_Ori = uint8(zeros(imInfo.Height, imInfo.Width, 1, NumImages));
                    wb = waitbar(0,sprintf('Importing images. Please wait! \n %d / %d Images', 0, NumImages));
                    for i=1:NumImages
                        referenceFrame = imread(fullfile(PathInputFolder, dirList(i).name));
                        [row, col, ch, t] = size(referenceFrame);
                        if i>1 && (row_old ~= row || col_old ~= col || ch_old ~= ch || t_old ~= t)
                            errorString = {['All the input images must be of the same size.';'';'Image: "' char(dirList(i).name) '" is of a different size.']};
                            appTextDlg(app, errorString, 'Error', 'error')
                            return
                        end
                        flag_error1 = 0;
                        if ~isa(referenceFrame, 'uint8')
                            flag_error1 = 1;
                        end
                        if size(referenceFrame, 3)~=1
                            flag_error1 = 1;
                            if i==1 % only at the first image occurence
                                inputOptions={'Red','Green','Blue','Gray conversion'};
                                defSelection=inputOptions{4};
                                iSel=bttnChoiseDialog(inputOptions, 'Load images', defSelection,...
                                    'Which channel do you want load?');
                                flag_channelToLoad = iSel;
                            end
                            if flag_channelToLoad == 1 || flag_channelToLoad == 2 || flag_channelToLoad == 3
                                referenceFrame = referenceFrame(:,:,flag_channelToLoad);
                            elseif flag_channelToLoad == 4
                                referenceFrame = rgb2gray(referenceFrame);
                            else
                                errorString = {'Incorrect selection.'};
                                appTextDlg(app, errorString, 'Error', 'error')
                            end
                        end
                        if flag_error1==1 && i==1
                            errorString = {'The input images must be gray-level 8-bit images.';'';'The images have been converted accordingly.'};
                            appTextDlg(app, errorString, 'Message', 'help')
                        end
                        Imgs_Ori(:,:,1,i) = uint8(referenceFrame);
                        clear flag_error1 referenceFrame
                        row_old = row; col_old = col; ch_old = ch; t_old = t;
                        if ishandle(wb)
                            waitbar(i/NumImages,wb,sprintf('Importing images. Please wait! \n %d / %d Images', i, NumImages));
                        else
                            wb = waitbar(i,sprintf('Importing images. Please wait! \n %d / %d Images', i, NumImages));
                        end
                    end
                    if ishandle(wb), close(wb), end

                    % Compute MinValue and MaxValue
                    MinValue = min(Imgs_Ori,[],'all');
                    MaxValue = max(Imgs_Ori,[],'all');

                    % Update MinValue and MaxValue
                    if MaxValue <= MinValue
                        errorString = {'The minimum and the maximum values of the input images are coincident.'};
                        appTextDlg(app, errorString, 'Error', 'error')
                        return
                    end

                    % Check for seeing if the user click on the correct load button
                    flagEstimated_FluorescenceImages = checkFluorescentImages(Imgs_Ori(:,:,1,i));
                    if flagEstimated_FluorescenceImages ~= app.comet_handles.FluorescenceImages
                        % Construct a questdlg with two options
                        if app.comet_handles.FluorescenceImages==1
                            choice = questdlg('Are you sure that you loaded fluorescent images?', ...
                                'Check input images', ...
                                'Yes','No','Yes');
                        else
                            choice = questdlg('Are you sure that you loaded non-fluorescent images?', ...
                                'Check input images', ...
                                'Yes','No','Yes');
                        end
                        % Handle response
                        switch choice
                            case 'Yes'
                            otherwise
                                errorString = {'Please, re-load the images using the correct button.'};
                                appTextDlg(app, errorString, 'Error', 'error')
                                clear Imgs_Ori MinValue MaxValue referenceFrame ImageName PathInputFolder dirList
                                app.comet_handles.LoadImagesOngoing = 0;
                                app.comet_handles.FluorescenceImages = 0;
                                return
                        end
                    end

                    % To leave for masks saving purposes the value 255
                    a = double(0);   %Theoretically->double(0);
                    b = double(255); %Theoretically->double(255);
                    % Save the images
                    c = double(MinValue); % Original Intesity values
                    d = double(MaxValue); % Original Intesity values

                    % Modify the original images by stretching
                    Imgs_Stretched = Imgs_Ori;
                    Imgs_Stretched(Imgs_Stretched>MaxValue) = MaxValue;
                    Imgs_Stretched(Imgs_Stretched<MinValue) = MinValue;
                    Imgs_Stretched = ((Imgs_Stretched-c).*((b-a)/(d-c)))+a;
                    if app.comet_handles.FluorescenceImages == 0
                        Imgs_Stretched = imcomplement(Imgs_Stretched);
                        Imgs_Ori = imcomplement(Imgs_Ori);
                    end

                    Imgs_Stretched(:,:,2:3,:) = 0;
                    reset_Comet_Handles(app)

                    % Save parameters
                    app.comet_handles.IntensityMinValStretched = a;
                    app.comet_handles.IntensityMaxValStretched = b;
                    app.comet_handles.IntensityMinValOrig = c;
                    app.comet_handles.IntensityMaxValOrig = d;
                    app.comet_handles.Imgs_Stretched = Imgs_Stretched;
                    app.comet_handles.Imgs_Ori = Imgs_Ori;
                    app.comet_handles.NumImages = NumImages;
                    app.comet_handles.ImageFormat = ImageFormat;
                    [imHigh, imWidth, ~, ~] = size(Imgs_Stretched);
                    app.comet_handles.ImageSize = [imHigh, imWidth];
                    app.comet_handles.dirList = dirList;
                    app.comet_handles.ImgsNames = ImgsNames;
                    app.comet_handles.IndImgShown = 1;
                    app.comet_handles.PathInputFolderOriginal = PathInputFolder;
                    set(app.text_Num,'Text', ['Image: ' '1' '/' num2str(NumImages)]);
                    set(app.text_Name,'Text', ['Image: ' char(app.comet_handles.dirList(ceil(1)).name)]);
                    imshow(composeImage(app.comet_handles.Imgs_Stretched(:,:,:,app.comet_handles.IndImgShown)), [], 'Parent', app.axes1);
                    app.axes1.XAxis.Visible = 'off';
                    app.axes1.YAxis.Visible = 'off';
                    app.axes1.XTickLabel = [];
                    app.axes1.YTickLabel = [];
                    app.axes1.BackgroundColor = [0 0 0];
                    app.axes1.Toolbar.Visible = "off";
                    app.axes1.XLim = [0, app.comet_handles.ImageSize(2)];
                    app.axes1.YLim = [0, app.comet_handles.ImageSize(1)];
                    pause(.1)
                    drawnow
                    pause(.1)
                    resetComponents(app);

                catch
                    errorString = {['Please, select an image.';'';'';...
                        'The input folder must contain only images and no other files.';'';'';...
                        'The basename of the images cannot contain spaces and special characters, and must be in the format: "BasenameImage_###.ImageFormat".']};
                    appTextDlg(app, errorString, 'Error', 'error')
                    app.comet_handles.LoadImagesOngoing = 0;
                    app.comet_handles.FluorescenceImages = 0;
                end

            else
                app.comet_handles.FluorescenceImages = 0;
            end
        end

        % Menu selected function: Menu_File_SaveData
        function Menu_File_SaveData_Callback(app, event)
            % Auto save of the project
            comet_handles = app.comet_handles;  %#ok
            comet_handles.ROI_ULCyx_DRCyx = [];  %#ok
            comet_handles.ROIori = [];  %#ok
            comet_handles.ROIoriFiltered = [];  %#ok
            comet_handles.ROIsegm = [];  %#ok
            comet_handles.MaskComet = [];  %#ok
            comet_handles.MaskHead = [];  %#ok
            comet_handles.ROIshown = 0;  %#ok
            comet_handles.LoadImagesOngoing = 0;  %#ok
            comet_handles.SelectACometOngoing = 0;  %#ok
            comet_handles.flag_CometFitFreehand = 0;  %#ok

            if app.comet_handles.FlagNewComets>=app.comet_handles.AutoSaveEveryNComets
                uisave({'comet_handles'},'Autosave_AnalysedDataset.mat');
            else
                uisave({'comet_handles'},'AnalysedDataset.mat');
            end
            app.comet_handles.FlagNewComets = 0;
        end

        % Menu selected function: Menu_Help_About
        function Menu_Help_About_Callback(app, event)
            Message = {'SOFTWARE VERSION';...
                'CometAnalyser v1.0';...
                'March 01, 2022';...
                '';...
                '';...
                'REQUIREMENTS';...
                'MATLAB R2021a,';...
                'Image Processing Toolbox 11.2,';...
                'Deep Learning Toolbox 14.2,';...
                'Statistics and Machine Learning Toolbox 12.1, or later versions.';...
                '';...
                '';...
                'MAIN CONTACTS';...
                'Eng. Filippo Piccinini, PhD, email: filippo.piccinini85@gmail.com';...
                'Eng. Attila Beleon, email: beleonattila@gmail.com';...
                '';...
                '';...
                'LICENSE';...
                'Copyright (c) 2017, Filippo Piccinini, Attila Beleon.';...
                'All rights reserved.';...
                'CometAnalyser is licensed under the: 3-clause BSD License';...
                ['This software is provided by the copyright holders and contributors "as is" and any express or implied warranties, ',...
                'including, but not limited to, the implied warranties of merchantability and fitness for a particular purpose are disclaimed. ',...
                'In no event shall <copyright holder> be liable for any direct, indirect, incidental, special, exemplary, ',...
                'or consequential damages (including, but not limited to, procurement of substitute goods or services; ',...
                'loss of use, data, or profits; or business interruption) however caused and on any theory of liability, ',...
                'whether in contract, strict liability, or tort (including negligence or otherwise) ',...
                'arising in any way out of the use of this software, even if advised of the possibility of such damage.'];...
                '';...
                ''};
            appTextDlg(app, Message, 'About Us', 'none')

        end

        % Button pushed function: but_CometAdd
        function but_CometAdd_Callback(app, event)
            if app.comet_handles.SelectACometOngoing == 1 || isempty(app.comet_handles.NumImages) || app.comet_handles.NumImages == 0
                return
            end
            if app.comet_handles.ROIshown == 1
                className = app.pop_class.Value;
                [bool, warnString] = addComet(app,className);
                pause(.1)
                app.axes1.Children.CData = composeImage(app.comet_handles.Imgs_Stretched(:,:,:,app.comet_handles.IndImgShown));
                pause(.1)
                drawnow
                if strcmp(app.PredictionMenu.Checked,'on')
                    app.PredictionMenu.Checked = 'off';
                end
                if bool == 0
                    appTextDlg(app, warnString, 'Message', 'error')
                    return
                else
                    % Auto save of the project
                    if app.comet_handles.AutoSaveEveryNComets ~= 0
                        if app.comet_handles.FlagNewComets>=app.comet_handles.AutoSaveEveryNComets
                            Menu_File_SaveData_Callback(app);
                        end
                    end
                    if ~isempty(app.classBrowser_handle)
                        app.classBrowser_handle.recalcCatalog(0);
                        app.classBrowser_handle.refreshCatalogPlot;
                    end
                end
                app.scope.ImageSource = app.CometIcon;
                app.selectedComet = [];
                app.comet_handles.ROIshown = 0;
                app.comet_handles.ROIori = [];
                app.comet_handles.ROIoriFiltered = [];
                app.comet_handles.ROIsegm = [];
                app.comet_handles.MaskComet = [];
                app.comet_handles.MaskHead = [];
                app.comet_handles.ROI_ULCyx_DRCyx = [];
            end
        end

        % Button pushed function: but_CometRemove
        function but_CometRemove_Callback(app, event)
            if app.comet_handles.SelectACometOngoing == 1 || isempty(app.comet_handles.NumImages) || app.comet_handles.NumImages == 0
                return
            end
            if ~isempty(app.selectedComet)
                [bool, warnString] = removeComet(app);
                app.axes1.Children.CData = composeImage(app.comet_handles.Imgs_Stretched(:,:,:,app.comet_handles.IndImgShown));
                if strcmp(app.PredictionMenu.Checked,'on')
                    app.PredictionMenu.Checked = 'off';
                end
                app.scope.ImageSource = app.CometIcon;
                app.selectedComet = [];
                app.comet_handles.ROIshown = 0;
                app.comet_handles.ROIori = [];
                app.comet_handles.ROIoriFiltered = [];
                app.comet_handles.ROIsegm = [];
                app.comet_handles.ROI_ULCyx_DRCyx = [];
                if bool == 0
                    appTextDlg(app, warnString, 'Message', 'error')
                else
                    if ~isempty(app.classBrowser_handle)
                        app.classBrowser_handle.recalcCatalog(app, 0);
                        app.classBrowser_handle.refreshCatalogPlot;
                    end
                end
            end
        end

        % Button pushed function: but_Left
        function but_Left_Callback(app, event)

            app.scope.ImageSource = app.CometIcon;
            app.selectedComet = [];
            app.comet_handles.ROIshown = 0;
            app.comet_handles.ROIori = [];
            app.comet_handles.ROIoriFiltered = [];
            app.comet_handles.ROIsegm = [];
            app.comet_handles.ROI_ULCyx_DRCyx = [];

            NumImages = app.comet_handles.NumImages;
            if isempty(NumImages) || NumImages == 0
                return
            end
            IndImgShown = app.comet_handles.IndImgShown;

            if IndImgShown == 1
                IndImgShown = NumImages;
            else
                IndImgShown = IndImgShown - 1;
            end

            app.comet_handles.IndImgShown = IndImgShown;
            set(app.text_Num,'Text', ['Image: ' num2str(IndImgShown) '/' num2str(NumImages)]);
            set(app.text_Name,'Text', ['Image: ' char(app.comet_handles.ImgsNames{IndImgShown})]);
            if ~isempty(app.imDatatipText)
                delete(app.imDatatipText)
                app.imDatatipText = [];
            end
            app.axes1.Children.CData = composeImage(app.comet_handles.Imgs_Stretched(:,:,:,app.comet_handles.IndImgShown));
            if ~isempty(app.ClassifierModel) && strcmp(app.PredictionMenu.Checked,'on')
                app.PredictionMenu.Checked = 'off';
                PredictionMenuSelected(app)
            end
        end

        % Button pushed function: but_Right
        function but_Right_Callback(app, event)

            app.scope.ImageSource = app.CometIcon;
            app.selectedComet = [];
            app.comet_handles.ROIshown = 0;
            app.comet_handles.ROIori = [];
            app.comet_handles.ROIoriFiltered = [];
            app.comet_handles.ROIsegm = [];
            app.comet_handles.ROI_ULCyx_DRCyx = [];

            NumImages = app.comet_handles.NumImages;
            if isempty(NumImages) || NumImages == 0
                return
            end
            IndImgShown = app.comet_handles.IndImgShown;

            if IndImgShown == NumImages
                IndImgShown = 1;
            else
                IndImgShown = IndImgShown + 1;
            end

            app.comet_handles.IndImgShown = IndImgShown;
            set(app.text_Num,'Text', ['Image: ' num2str(IndImgShown) '/' num2str(NumImages)]);
            set(app.text_Name,'Text', ['Image: ' char(app.comet_handles.ImgsNames{IndImgShown})]);
            if ~isempty(app.imDatatipText)
                delete(app.imDatatipText)
                app.imDatatipText = [];
            end
            app.axes1.Children.CData = composeImage(app.comet_handles.Imgs_Stretched(:,:,:,app.comet_handles.IndImgShown));
            if ~isempty(app.ClassifierModel) && strcmp(app.PredictionMenu.Checked,'on')
                app.PredictionMenu.Checked = 'off';
                PredictionMenuSelected(app)
            end
        end

        % Button pushed function: but_SelectComet
        function but_SelectComet_Callback(app, event)
            if app.comet_handles.SelectACometOngoing == 1 || isempty(app.comet_handles.NumImages) || app.comet_handles.NumImages == 0
                return
            end

            %To avoid two segmentation in the same moment
            app.comet_handles.SelectACometOngoing = 1;
            % To stop disable the GUI
            InterfaceObj=findobj(app.figure1,'Enable','on');
            set(InterfaceObj,'Enable','off');
            set(app.Panel,'Enable','on');
            set(app.uipanel1,'Enable','on');

            [iscomplete, errorString] = selecCometFunction(app);

            if iscomplete == 0
                app.comet_handles.SelectACometOngoing = 0;
                if exist('hFigFree2', 'var'); delete(hFigFree2); end
                appTextDlg(app, errorString, 'No comet has been selected', 'error')
                app.scope.ImageSource = app.CometIcon;
                app.selectedComet = [];
                app.comet_handles.SelectACometOngoing = 0;
                app.comet_handles.ROIshown = 0;
                app.comet_handles.ROIori = [];
                app.comet_handles.ROIoriFiltered = [];
                app.comet_handles.ROIsegm = [];
                app.comet_handles.MaskComet = [];
                app.comet_handles.MaskHead = [];
                app.comet_handles.ROI_ULCyx_DRCyx = [];
            end

            set(InterfaceObj,'Enable','on');
            app.comet_handles.SelectACometOngoing = 0;
        end

        % Button pushed function: but_ShowOriImg
        function but_ShowOriImg_Callback(app, event)
            if app.comet_handles.SelectACometOngoing == 1
                return
            end

            if ishandle(app.comet_handles.h_GUIhelpA); close(app.comet_handles.h_GUIhelpA); end

            IndImgShown = app.comet_handles.IndImgShown;
            dirList = app.comet_handles.dirList;
            PathInputFolder = app.comet_handles.PathInputFolderOriginal;

            if ~isempty(PathInputFolder) && all(PathInputFolder~=0)
                try
                    referenceFrame = imread(fullfile(PathInputFolder, dirList(IndImgShown).name));
                    app.tempFig = figure;
                    newAx = axes(app.tempFig);
                    imshow(referenceFrame, 'Parent', newAx, 'Border', 'Tight')
                catch
                    try close(BarWaitWindows); catch; end
                    errorString = {'Impossible to open the original image.'};
                    appTextDlg(app, errorString, 'Error', 'error')
                end
            else
                try close(BarWaitWindows); catch; end
                errorString = {'Impossible to open the original images of a loaded project.'};
                appTextDlg(app, errorString, 'Error', 'error')
            end
        end

        % Button pushed function: but_diHead_less
        function but_diHead_less_Callback(app, event)
            flag_CurrentCometType = app.comet_handles.flag_CurrentCometType;

            if app.comet_handles.ROIshown == 1 && ~isempty(flag_CurrentCometType) && flag_CurrentCometType==3
                MaskComet = app.comet_handles.MaskComet;
                HeadSizeDiskDilation = app.comet_handles.HeadSizeDiskDilation;
                HeadSizeDiskDilation = HeadSizeDiskDilation - 1;
                if HeadSizeDiskDilation >= 0
                    app.comet_handles.HeadSizeDiskDilation = HeadSizeDiskDilation;
                    set(app.text_DilationHead,'Text', num2str(HeadSizeDiskDilation));

                    % Set slider
                    sliderValue_0_1 = HeadSizeDiskDilation/150;
                    set(app.slider_head_Dilation,'Value', sliderValue_0_1);

                    ROIori = app.comet_handles.ROIori;
                    ROIoriFiltered = app.comet_handles.ROIoriFiltered;

                    [MaskHead, ~] = segmentHead(ROIoriFiltered, MaskComet, app.comet_handles.HeadThAddFactor, app.comet_handles.HeadSizeDiskDilation, app.comet_handles.flag_ThresholdHeadMode);

                    % To delete external pixels in case of perfect fit.
                    flag_CometFitFreehand = app.comet_handles.flag_CometFitFreehand;
                    ROIsegm = app.comet_handles.ROIsegm;
                    if flag_CometFitFreehand == 1
                        if ~isempty(MaskHead)
                            MaskHead(ROIsegm==0)=0;
                        end
                        if ~isempty(MaskComet)
                            MaskComet(ROIsegm==0)=0;
                        end
                    end

                    ROIcomposed = falseColorsComet(ROIori, logical(MaskHead), logical(MaskComet), flag_CurrentCometType);

                    app.scope.ImageSource = uint8(ROIcomposed);


                    app.comet_handles.MaskComet = MaskComet;
                    app.comet_handles.MaskHead = MaskHead;
                end
            end
        end

        % Button pushed function: but_diHead_plus
        function but_diHead_plus_Callback(app, event)
            flag_CurrentCometType = app.comet_handles.flag_CurrentCometType;

            if app.comet_handles.ROIshown == 1 && ~isempty(flag_CurrentCometType) && flag_CurrentCometType==3
                MaskComet = app.comet_handles.MaskComet;
                HeadSizeDiskDilation = app.comet_handles.HeadSizeDiskDilation;
                HeadSizeDiskDilation = HeadSizeDiskDilation + 1;
                if HeadSizeDiskDilation >= 0
                    app.comet_handles.HeadSizeDiskDilation = HeadSizeDiskDilation;
                    set(app.text_DilationHead,'Text', num2str(HeadSizeDiskDilation));

                    % Set slider
                    sliderValue_0_1 = HeadSizeDiskDilation/150;
                    set(app.slider_head_Dilation,'Value', sliderValue_0_1);

                    ROIori = app.comet_handles.ROIori;
                    ROIoriFiltered = app.comet_handles.ROIoriFiltered;

                    [MaskHead, ~] = segmentHead(ROIoriFiltered, MaskComet, app.comet_handles.HeadThAddFactor, app.comet_handles.HeadSizeDiskDilation, app.comet_handles.flag_ThresholdHeadMode);

                    % To delete external pixels in case of perfect fit.
                    flag_CometFitFreehand = app.comet_handles.flag_CometFitFreehand;
                    ROIsegm = app.comet_handles.ROIsegm;
                    if flag_CometFitFreehand == 1
                        if ~isempty(MaskHead)
                            MaskHead(ROIsegm==0)=0;
                        end
                        if ~isempty(MaskComet)
                            MaskComet(ROIsegm==0)=0;
                        end
                    end

                    ROIcomposed = falseColorsComet(ROIori, logical(MaskHead), logical(MaskComet), flag_CurrentCometType);

                    app.scope.ImageSource = uint8(ROIcomposed);


                    app.comet_handles.MaskComet = MaskComet;
                    app.comet_handles.MaskHead = MaskHead;
                end
            end
        end

        % Button pushed function: but_di_less
        function but_di_less_Callback(app, event)
            flag_CurrentCometType = app.comet_handles.flag_CurrentCometType;

            if app.comet_handles.ROIshown == 1 && ~isempty(flag_CurrentCometType)
                MaskHead = app.comet_handles.MaskHead;
                CometSizeDiskDilation = app.comet_handles.CometSizeDiskDilation;
                CometSizeDiskDilation = CometSizeDiskDilation - 1;
                if CometSizeDiskDilation >= 0
                    app.comet_handles.CometSizeDiskDilation = CometSizeDiskDilation;
                    set(app.text_Dilation,'Text', num2str(CometSizeDiskDilation));

                    % Set slider
                    sliderValue_0_1 = CometSizeDiskDilation/150;
                    set(app.slider_comet_Dilation,'Value', sliderValue_0_1);

                    ROIori = app.comet_handles.ROIori;
                    ROIoriFiltered = app.comet_handles.ROIoriFiltered;

                    % Segment comet
                    flag_CometFitFreehand = app.comet_handles.flag_CometFitFreehand;
                    if flag_CometFitFreehand == 1
                        [MaskComet, ~] = segmentComet(ROIoriFiltered, app.comet_handles.ROIsegm, 0, 0, 0);
                    else
                        [MaskComet, ~] = segmentComet(ROIoriFiltered, app.comet_handles.ROIsegm, app.comet_handles.CometThAddFactor, app.comet_handles.CometSizeDiskDilation, app.comet_handles.flag_ThresholdMode);
                    end
                    if flag_CurrentCometType == 1
                        MaskHead = MaskComet;
                    end

                    % To delete external pixels in case of perfect fit.
                    ROIsegm = app.comet_handles.ROIsegm;
                    if flag_CometFitFreehand == 1
                        if ~isempty(MaskHead)
                            MaskHead(ROIsegm==0)=0;
                        end
                        if ~isempty(MaskComet)
                            MaskComet(ROIsegm==0)=0;
                        end
                    end

                    ROIcomposed = falseColorsComet(ROIori, logical(MaskHead), logical(MaskComet), flag_CurrentCometType);

                    app.scope.ImageSource = uint8(ROIcomposed);


                    app.comet_handles.MaskComet = MaskComet;
                    app.comet_handles.MaskHead = MaskHead;
                end
            end
        end

        % Button pushed function: but_di_plus
        function but_di_plus_Callback(app, event)
            flag_CurrentCometType = app.comet_handles.flag_CurrentCometType;

            if app.comet_handles.ROIshown == 1 && ~isempty(flag_CurrentCometType)
                MaskHead = app.comet_handles.MaskHead;
                CometSizeDiskDilation = app.comet_handles.CometSizeDiskDilation;
                CometSizeDiskDilation = CometSizeDiskDilation + 1;
                if CometSizeDiskDilation >= 0
                    app.comet_handles.CometSizeDiskDilation = CometSizeDiskDilation;
                    set(app.text_Dilation,'Text', num2str(CometSizeDiskDilation));

                    % Set slider
                    sliderValue_0_1 = CometSizeDiskDilation/150;
                    set(app.slider_comet_Dilation,'Value', sliderValue_0_1);

                    ROIori = app.comet_handles.ROIori;
                    ROIoriFiltered = app.comet_handles.ROIoriFiltered;

                    % Segment comet
                    flag_CometFitFreehand = app.comet_handles.flag_CometFitFreehand;
                    if flag_CometFitFreehand == 1
                        [MaskComet, ~] = segmentComet(ROIoriFiltered, app.comet_handles.ROIsegm, 0, 0, 0);
                    else
                        [MaskComet, ~] = segmentComet(ROIoriFiltered, app.comet_handles.ROIsegm, app.comet_handles.CometThAddFactor, app.comet_handles.CometSizeDiskDilation, app.comet_handles.flag_ThresholdMode);
                    end
                    if flag_CurrentCometType == 1
                        MaskHead = MaskComet;
                    end

                    % To delete external pixels in case of perfect fit.
                    ROIsegm = app.comet_handles.ROIsegm;
                    if flag_CometFitFreehand == 1
                        if ~isempty(MaskHead)
                            MaskHead(ROIsegm==0)=0;
                        end
                        if ~isempty(MaskComet)
                            MaskComet(ROIsegm==0)=0;
                        end
                    end

                    ROIcomposed = falseColorsComet(ROIori, logical(MaskHead), logical(MaskComet), flag_CurrentCometType);

                    app.scope.ImageSource = uint8(ROIcomposed);


                    app.comet_handles.MaskComet = MaskComet;
                    app.comet_handles.MaskHead = MaskHead;
                end
            end
        end

        % Button pushed function: but_thHead_less
        function but_thHead_less_Callback(app, event)
            flag_CurrentCometType = app.comet_handles.flag_CurrentCometType;

            if app.comet_handles.ROIshown == 1 && ~isempty(flag_CurrentCometType) && flag_CurrentCometType==3
                MaskComet = app.comet_handles.MaskComet;
                HeadThAddFactor = app.comet_handles.HeadThAddFactor;
                HeadThAddFactor = HeadThAddFactor - 1;
                app.comet_handles.HeadThAddFactor = HeadThAddFactor;
                set(app.text_ThresholdHead,'Text', num2str(HeadThAddFactor));

                % Set slider
                sliderValue_0_1 = 0.5 + HeadThAddFactor/(255*2);
                set(app.slider_head_threshold,'Value', sliderValue_0_1);

                ROIori = app.comet_handles.ROIori;
                ROIoriFiltered = app.comet_handles.ROIoriFiltered;

                [MaskHead, ~] = segmentHead(ROIoriFiltered, MaskComet, app.comet_handles.HeadThAddFactor, app.comet_handles.HeadSizeDiskDilation, app.comet_handles.flag_ThresholdHeadMode);

                % To delete external pixels in case of perfect fit.
                flag_CometFitFreehand = app.comet_handles.flag_CometFitFreehand;
                ROIsegm = app.comet_handles.ROIsegm;
                if flag_CometFitFreehand == 1
                    if ~isempty(MaskHead)
                        MaskHead(ROIsegm==0)=0;
                    end
                    if ~isempty(MaskComet)
                        MaskComet(ROIsegm==0)=0;
                    end
                end

                ROIcomposed = falseColorsComet(ROIori, logical(MaskHead), logical(MaskComet), flag_CurrentCometType);

                app.scope.ImageSource = uint8(ROIcomposed);


                app.comet_handles.MaskComet = MaskComet;
                app.comet_handles.MaskHead = MaskHead;
            end
        end

        % Button pushed function: but_thHead_plus
        function but_thHead_plus_Callback(app, event)
            flag_CurrentCometType = app.comet_handles.flag_CurrentCometType;

            if app.comet_handles.ROIshown == 1 && ~isempty(flag_CurrentCometType) && flag_CurrentCometType==3
                MaskComet = app.comet_handles.MaskComet;
                HeadThAddFactor = app.comet_handles.HeadThAddFactor;
                HeadThAddFactor = HeadThAddFactor + 1;
                app.comet_handles.HeadThAddFactor = HeadThAddFactor;
                set(app.text_ThresholdHead,'Text', num2str(HeadThAddFactor));

                % Set slider
                sliderValue_0_1 = 0.5 + HeadThAddFactor/(255*2);
                set(app.slider_head_threshold,'Value', sliderValue_0_1);

                ROIori = app.comet_handles.ROIori;
                ROIoriFiltered = app.comet_handles.ROIoriFiltered;

                [MaskHead, ~] = segmentHead(ROIoriFiltered, MaskComet, app.comet_handles.HeadThAddFactor, app.comet_handles.HeadSizeDiskDilation, app.comet_handles.flag_ThresholdHeadMode);

                % To delete external pixels in case of perfect fit.
                flag_CometFitFreehand = app.comet_handles.flag_CometFitFreehand;
                ROIsegm = app.comet_handles.ROIsegm;
                if flag_CometFitFreehand == 1
                    if ~isempty(MaskHead)
                        MaskHead(ROIsegm==0)=0;
                    end
                    if ~isempty(MaskComet)
                        MaskComet(ROIsegm==0)=0;
                    end
                end

                ROIcomposed = falseColorsComet(ROIori, logical(MaskHead), logical(MaskComet), flag_CurrentCometType);

                app.scope.ImageSource = uint8(ROIcomposed);

                app.comet_handles.MaskComet = MaskComet;
                app.comet_handles.MaskHead = MaskHead;
            end
        end

        % Button pushed function: but_th_less
        function but_th_less_Callback(app, event)
            flag_CurrentCometType = app.comet_handles.flag_CurrentCometType;

            if app.comet_handles.ROIshown == 1 && ~isempty(flag_CurrentCometType)
                MaskHead = app.comet_handles.MaskHead;
                CometThAddFactor = app.comet_handles.CometThAddFactor;
                CometThAddFactor = CometThAddFactor - 1;
                app.comet_handles.CometThAddFactor = CometThAddFactor;
                set(app.text_Threshold,'Text', num2str(CometThAddFactor));

                % Set slider
                sliderValue_0_1 = 0.5 + CometThAddFactor/(255*2);
                set(app.slider_comet_threshold,'Value', sliderValue_0_1);

                ROIori = app.comet_handles.ROIori;
                ROIoriFiltered = app.comet_handles.ROIoriFiltered;

                % Segment comet
                flag_CometFitFreehand = app.comet_handles.flag_CometFitFreehand;
                if flag_CometFitFreehand == 1
                    [MaskComet, ~] = segmentComet(ROIoriFiltered, app.comet_handles.ROIsegm, 0, 0, 0);
                else
                    [MaskComet, ~] = segmentComet(ROIoriFiltered, app.comet_handles.ROIsegm, app.comet_handles.CometThAddFactor, app.comet_handles.CometSizeDiskDilation, app.comet_handles.flag_ThresholdMode);
                end
                if flag_CurrentCometType == 1
                    MaskHead = MaskComet;
                end

                % To delete external pixels in case of perfect fit.
                ROIsegm = app.comet_handles.ROIsegm;
                if flag_CometFitFreehand == 1
                    if ~isempty(MaskHead)
                        MaskHead(ROIsegm==0)=0;
                    end
                    if ~isempty(MaskComet)
                        MaskComet(ROIsegm==0)=0;
                    end
                end

                ROIcomposed = falseColorsComet(ROIori, logical(MaskHead), logical(MaskComet), flag_CurrentCometType);

                app.scope.ImageSource = uint8(ROIcomposed);


                app.comet_handles.MaskComet = MaskComet;
                app.comet_handles.MaskHead = MaskHead;
            end
        end

        % Button pushed function: but_th_plus
        function but_th_plus_Callback(app, event)
            flag_CurrentCometType = app.comet_handles.flag_CurrentCometType;

            if app.comet_handles.ROIshown == 1 && ~isempty(flag_CurrentCometType)
                MaskHead = app.comet_handles.MaskHead;
                CometThAddFactor = app.comet_handles.CometThAddFactor;
                CometThAddFactor = CometThAddFactor + 1;
                app.comet_handles.CometThAddFactor = CometThAddFactor;
                set(app.text_Threshold,'Text', num2str(CometThAddFactor));

                % Set slider
                sliderValue_0_1 = 0.5 + CometThAddFactor/(255*2);
                set(app.slider_comet_threshold,'Value', sliderValue_0_1);

                ROIori = app.comet_handles.ROIori;
                ROIoriFiltered = app.comet_handles.ROIoriFiltered;

                % Segment comet
                flag_CometFitFreehand = app.comet_handles.flag_CometFitFreehand;
                if flag_CometFitFreehand == 1
                    [MaskComet, ~] = segmentComet(ROIoriFiltered, app.comet_handles.ROIsegm, 0, 0, 0);
                else
                    [MaskComet, ~] = segmentComet(ROIoriFiltered, app.comet_handles.ROIsegm, app.comet_handles.CometThAddFactor, app.comet_handles.CometSizeDiskDilation, app.comet_handles.flag_ThresholdMode);
                end
                if flag_CurrentCometType == 1
                    MaskHead = MaskComet;
                end

                % To delete external pixels in case of perfect fit.
                ROIsegm = app.comet_handles.ROIsegm;
                if flag_CometFitFreehand == 1
                    if ~isempty(MaskHead)
                        MaskHead(ROIsegm==0)=0;
                    end
                    if ~isempty(MaskComet)
                        MaskComet(ROIsegm==0)=0;
                    end
                end

                ROIcomposed = falseColorsComet(ROIori, logical(MaskHead), logical(MaskComet), flag_CurrentCometType);

                app.scope.ImageSource = uint8(ROIcomposed);


                app.comet_handles.MaskComet = MaskComet;
                app.comet_handles.MaskHead = MaskHead;
            end
        end

        % Value changed function: check_CometFitFreehand
        function check_CometFitFreehand_Callback(app, event)
            if app.comet_handles.SelectACometOngoing == 1 || app.comet_handles.LoadImagesOngoing == 1
                set(app.check_CometFitFreehand,'Value',app.comet_handles.flag_CometFitFreehand);
                return
            end

            flag_CometFitFreehand = get(app.check_CometFitFreehand,'Value');
            app.comet_handles.flag_CometFitFreehand = flag_CometFitFreehand;

            if app.comet_handles.ROIshown == 1
                MaskHead = [];
                ROIori = app.comet_handles.ROIori;
                ROIoriFiltered = app.comet_handles.ROIoriFiltered;

                % Segment comet
                flag_CometFitFreehand = app.comet_handles.flag_CometFitFreehand;
                if flag_CometFitFreehand == 1
                    [MaskComet, ~] = segmentComet(ROIoriFiltered, app.comet_handles.ROIsegm, 0, 0, 0);
                else
                    [MaskComet, ~] = segmentComet(ROIoriFiltered, app.comet_handles.ROIsegm, app.comet_handles.CometThAddFactor, app.comet_handles.CometSizeDiskDilation, app.comet_handles.flag_ThresholdMode);
                end
                if app.comet_handles.flag_CurrentCometType == 1
                    MaskHead = MaskComet;
                end

                % If comet head present (3 = Present tail and head)
                if app.comet_handles.flag_CurrentCometType == 3
                    [MaskHead, ~] = segmentHead(ROIoriFiltered, MaskComet, app.comet_handles.HeadThAddFactor, app.comet_handles.HeadSizeDiskDilation);
                end

                % To delete external pixels in case of perfect fit.
                ROIsegm = app.comet_handles.ROIsegm;
                if flag_CometFitFreehand == 1
                    if ~isempty(MaskHead)
                        MaskHead(ROIsegm==0)=0;
                    end
                    if ~isempty(MaskComet)
                        MaskComet(ROIsegm==0)=0;
                    end
                end

                ROIcomposed = falseColorsComet(ROIori, logical(MaskHead), logical(MaskComet), app.comet_handles.flag_CometFitFreehand);

                app.scope.ImageSource = uint8(ROIcomposed);


                app.comet_handles.MaskComet = MaskComet;
                app.comet_handles.MaskHead = MaskHead;
            end
        end

        % Value changed function: pop_class
        function pop_class_Callback(app, event)
            if strcmp(app.pop_class.Value,'Prediction')
                app.comet_handles.ClassID = 255;
            else
                app.comet_handles.ClassID = app.comet_handles.Classes.(app.pop_class.Value).ID;
            end
        end

        % Value changed function: pop_tail
        function pop_tail_Callback(app, event)
            if app.comet_handles.SelectACometOngoing == 1
                set(app.pop_tail,'Value',app.pop_tail.Items(app.comet_handles.flag_CurrentCometType));
                return
            end


            app.scope.ImageSource = app.CometIcon;
            app.selectedComet = [];

            app.comet_handles.ROIshown = 0;
            app.comet_handles.ROIori = [];
            app.comet_handles.ROIoriFiltered = [];
            app.comet_handles.ROIsegm = [];
            app.comet_handles.MaskComet = [];
            app.comet_handles.MaskHead = [];
            app.comet_handles.ROI_ULCyx_DRCyx = [];

            %1 = No comet tail
            %2 = No comet head
            %3 = Present tail and head
            app.comet_handles.flag_CurrentCometType = find(strcmp(app.pop_tail.Items, app.pop_tail.Value));
        end

        % Value changed function: pop_thresholdHead
        function pop_thresholdHead_Callback(app, event)
            %1 = Otsu
            %2 = Triangle
            %3 = Average Otsu and Triangle
            app.comet_handles.flag_ThresholdHeadMode = find(strcmp(app.pop_thresholdHead.Items, app.pop_thresholdHead.Value));

            if app.comet_handles.ROIshown == 1
                MaskHead = [];
                ROIori = app.comet_handles.ROIori;
                ROIoriFiltered = app.comet_handles.ROIoriFiltered;

                flag_CurrentCometType = app.comet_handles.flag_CurrentCometType;

                % Segment comet
                flag_CometFitFreehand = app.comet_handles.flag_CometFitFreehand;
                if flag_CometFitFreehand == 1
                    [MaskComet, ~] = segmentComet(ROIoriFiltered, app.comet_handles.ROIsegm, 0, 0, 0);
                else
                    [MaskComet, ~] = segmentComet(ROIoriFiltered, app.comet_handles.ROIsegm, app.comet_handles.CometThAddFactor, app.comet_handles.CometSizeDiskDilation, app.comet_handles.flag_ThresholdMode);
                end
                if flag_CurrentCometType == 1
                    MaskHead = MaskComet;
                end

                % If comet head present (3 = Present tail and head)
                if flag_CurrentCometType == 3
                    [MaskHead, ~] = segmentHead(ROIoriFiltered, MaskComet, app.comet_handles.HeadThAddFactor, app.comet_handles.HeadSizeDiskDilation, app.comet_handles.flag_ThresholdHeadMode);
                end

                % To delete external pixels in case of perfect fit.
                ROIsegm = app.comet_handles.ROIsegm;
                if flag_CometFitFreehand == 1
                    if ~isempty(MaskHead)
                        MaskHead(ROIsegm==0)=0;
                    end
                    if ~isempty(MaskComet)
                        MaskComet(ROIsegm==0)=0;
                    end
                end

                ROIcomposed = falseColorsComet(ROIori, logical(MaskHead), logical(MaskComet), flag_CurrentCometType);

                app.scope.ImageSource = uint8(ROIcomposed);


                app.comet_handles.MaskComet = MaskComet;
                app.comet_handles.MaskHead = MaskHead;
            end
        end

        % Value changed function: pop_threshold
        function pop_threshold_Callback(app, event)
            %1 = Otsu
            %2 = Triangle
            %3 = Average Otsu and Triangle
            app.comet_handles.flag_ThresholdMode = find(strcmp(app.pop_threshold.Items, app.pop_threshold.Value));

            if app.comet_handles.ROIshown == 1
                MaskHead = [];
                ROIori = app.comet_handles.ROIori;
                ROIoriFiltered = app.comet_handles.ROIoriFiltered;

                flag_CurrentCometType = app.comet_handles.flag_CurrentCometType;

                % Segment comet
                flag_CometFitFreehand = app.comet_handles.flag_CometFitFreehand;
                if flag_CometFitFreehand == 1
                    [MaskComet, ~] = segmentComet(ROIoriFiltered, app.comet_handles.ROIsegm, 0, 0, 0);
                else
                    [MaskComet, ~] = segmentComet(ROIoriFiltered, app.comet_handles.ROIsegm, app.comet_handles.CometThAddFactor, app.comet_handles.CometSizeDiskDilation, app.comet_handles.flag_ThresholdMode);
                end
                if flag_CurrentCometType == 1
                    MaskHead = MaskComet;
                end

                % If comet head present (3 = Present tail and head)
                if flag_CurrentCometType == 3
                    [MaskHead, ~] = segmentHead(ROIoriFiltered, MaskComet, app.comet_handles.HeadThAddFactor, app.comet_handles.HeadSizeDiskDilation, app.comet_handles.flag_ThresholdHeadMode);
                end

                % To delete external pixels in case of perfect fit.
                ROIsegm = app.comet_handles.ROIsegm;
                if flag_CometFitFreehand == 1
                    if ~isempty(MaskHead)
                        MaskHead(ROIsegm==0)=0;
                    end
                    if ~isempty(MaskComet)
                        MaskComet(ROIsegm==0)=0;
                    end
                end

                ROIcomposed = falseColorsComet(ROIori, logical(MaskHead), logical(MaskComet), flag_CurrentCometType);

                app.scope.ImageSource = uint8(ROIcomposed);


                app.comet_handles.MaskComet = MaskComet;
                app.comet_handles.MaskHead = MaskHead;
            end
        end

        % Value changed function: slider_comet_Dilation
        function slider_comet_Dilation_Callback(app, event)
            flag_CurrentCometType = app.comet_handles.flag_CurrentCometType;

            if app.comet_handles.ROIshown == 1 && ~isempty(flag_CurrentCometType)
                MaskHead = app.comet_handles.MaskHead;

                % Read slider
                sliderValue_0_1 = get(app.slider_comet_Dilation,'Value');
                CometSizeDiskDilation = round((sliderValue_0_1)*150); % Max dilation imposed at 150 pixels

                if CometSizeDiskDilation >= 0
                    app.comet_handles.CometSizeDiskDilation = CometSizeDiskDilation;
                    set(app.text_Dilation,'Text', num2str(CometSizeDiskDilation));

                    ROIori = app.comet_handles.ROIori;
                    ROIoriFiltered = app.comet_handles.ROIoriFiltered;

                    % Segment comet
                    flag_CometFitFreehand = app.comet_handles.flag_CometFitFreehand;
                    if flag_CometFitFreehand == 1
                        [MaskComet, ~] = segmentComet(ROIoriFiltered, app.comet_handles.ROIsegm, 0, 0, 0);
                    else
                        [MaskComet, ~] = segmentComet(ROIoriFiltered, app.comet_handles.ROIsegm, app.comet_handles.CometThAddFactor, app.comet_handles.CometSizeDiskDilation, app.comet_handles.flag_ThresholdMode);
                    end
                    if flag_CurrentCometType == 1
                        MaskHead = MaskComet;
                    end

                    % To delete external pixels in case of perfect fit.
                    ROIsegm = app.comet_handles.ROIsegm;
                    if flag_CometFitFreehand == 1
                        if ~isempty(MaskHead)
                            MaskHead(ROIsegm==0)=0;
                        end
                        if ~isempty(MaskComet)
                            MaskComet(ROIsegm==0)=0;
                        end
                    end

                    ROIcomposed = falseColorsComet(ROIori, logical(MaskHead), logical(MaskComet), flag_CurrentCometType);

                    app.scope.ImageSource = uint8(ROIcomposed);


                    app.comet_handles.MaskComet = MaskComet;
                    app.comet_handles.MaskHead = MaskHead;
                end
            else
                % Set slider
                CometSizeDiskDilation = app.comet_handles.CometSizeDiskDilation;
                sliderValue_0_1 = CometSizeDiskDilation/150;
                set(app.slider_comet_Dilation,'Value', sliderValue_0_1);
            end
        end

        % Value changed function: slider_comet_threshold
        function slider_comet_threshold_Callback(app, event)
            flag_CurrentCometType = app.comet_handles.flag_CurrentCometType;

            if app.comet_handles.ROIshown == 1 && ~isempty(flag_CurrentCometType)
                MaskHead = app.comet_handles.MaskHead;

                % Read slider
                sliderValue_0_1 = get(app.slider_comet_threshold,'Value');
                CometThAddFactor = round((sliderValue_0_1-0.5)*(255*2));
                app.comet_handles.CometThAddFactor = CometThAddFactor;

                set(app.text_Threshold,'Text', num2str(CometThAddFactor));
                ROIori = app.comet_handles.ROIori;
                ROIoriFiltered = app.comet_handles.ROIoriFiltered;

                % Segment comet
                flag_CometFitFreehand = app.comet_handles.flag_CometFitFreehand;
                if flag_CometFitFreehand == 1
                    [MaskComet, ~] = segmentComet(ROIoriFiltered, app.comet_handles.ROIsegm, 0, 0, 0);
                else
                    [MaskComet, ~] = segmentComet(ROIoriFiltered, app.comet_handles.ROIsegm, app.comet_handles.CometThAddFactor, app.comet_handles.CometSizeDiskDilation, app.comet_handles.flag_ThresholdMode);
                end
                if flag_CurrentCometType == 1
                    MaskHead = MaskComet;
                end

                % To delete external pixels in case of perfect fit.
                ROIsegm = app.comet_handles.ROIsegm;
                if flag_CometFitFreehand == 1
                    if ~isempty(MaskHead)
                        MaskHead(ROIsegm==0)=0;
                    end
                    if ~isempty(MaskComet)
                        MaskComet(ROIsegm==0)=0;
                    end
                end

                ROIcomposed = falseColorsComet(ROIori, logical(MaskHead), logical(MaskComet), flag_CurrentCometType);

                app.scope.ImageSource = uint8(ROIcomposed);


                app.comet_handles.MaskComet = MaskComet;
                app.comet_handles.MaskHead = MaskHead;
            else
                % Set slider
                CometThAddFactor = app.comet_handles.CometThAddFactor;
                sliderValue_0_1 = 0.5 + CometThAddFactor/(255*2);
                set(app.slider_comet_threshold,'Value', sliderValue_0_1);
            end
        end

        % Value changed function: slider_head_Dilation
        function slider_head_Dilation_Callback(app, event)
            flag_CurrentCometType = app.comet_handles.flag_CurrentCometType;

            if app.comet_handles.ROIshown == 1 && ~isempty(flag_CurrentCometType) && flag_CurrentCometType==3
                MaskComet = app.comet_handles.MaskComet;

                % Read slider
                sliderValue_0_1 = get(app.slider_head_Dilation,'Value');
                HeadSizeDiskDilation = round((sliderValue_0_1)*150); % Max dilation imposed at 150 pixels

                if HeadSizeDiskDilation >= 0
                    app.comet_handles.HeadSizeDiskDilation = HeadSizeDiskDilation;
                    set(app.text_DilationHead,'Text', num2str(HeadSizeDiskDilation));

                    ROIori = app.comet_handles.ROIori;
                    ROIoriFiltered = app.comet_handles.ROIoriFiltered;

                    [MaskHead, ~] = segmentHead(ROIoriFiltered, MaskComet, app.comet_handles.HeadThAddFactor, app.comet_handles.HeadSizeDiskDilation, app.comet_handles.flag_ThresholdHeadMode);

                    % To delete external pixels in case of perfect fit.
                    flag_CometFitFreehand = app.comet_handles.flag_CometFitFreehand;
                    ROIsegm = app.comet_handles.ROIsegm;
                    if flag_CometFitFreehand == 1
                        if ~isempty(MaskHead)
                            MaskHead(ROIsegm==0)=0;
                        end
                        if ~isempty(MaskComet)
                            MaskComet(ROIsegm==0)=0;
                        end
                    end

                    ROIcomposed = falseColorsComet(ROIori, logical(MaskHead), logical(MaskComet), flag_CurrentCometType);

                    app.scope.ImageSource = uint8(ROIcomposed);


                    app.comet_handles.MaskComet = MaskComet;
                    app.comet_handles.MaskHead = MaskHead;
                end
            else
                % Set slider
                HeadSizeDiskDilation = app.comet_handles.HeadSizeDiskDilation;
                sliderValue_0_1 = HeadSizeDiskDilation/150;
                set(app.slider_head_Dilation,'Value', sliderValue_0_1);
            end
        end

        % Value changed function: slider_head_threshold
        function slider_head_threshold_Callback(app, event)
            flag_CurrentCometType = app.comet_handles.flag_CurrentCometType;

            if app.comet_handles.ROIshown == 1 && ~isempty(flag_CurrentCometType) && flag_CurrentCometType==3
                MaskComet = app.comet_handles.MaskComet;

                % Read slider
                sliderValue_0_1 = get(app.slider_head_threshold,'Value');
                HeadThAddFactor = round((sliderValue_0_1-0.5)*(255*2));
                app.comet_handles.HeadThAddFactor = HeadThAddFactor;

                set(app.text_ThresholdHead,'Text', num2str(HeadThAddFactor));
                ROIori = app.comet_handles.ROIori;
                ROIoriFiltered = app.comet_handles.ROIoriFiltered;

                [MaskHead, ~] = segmentHead(ROIoriFiltered, MaskComet, app.comet_handles.HeadThAddFactor, app.comet_handles.HeadSizeDiskDilation, app.comet_handles.flag_ThresholdHeadMode);

                % To delete external pixels in case of perfect fit.
                flag_CometFitFreehand = app.comet_handles.flag_CometFitFreehand;
                ROIsegm = app.comet_handles.ROIsegm;
                if flag_CometFitFreehand == 1
                    if ~isempty(MaskHead)
                        MaskHead(ROIsegm==0)=0;
                    end
                    if ~isempty(MaskComet)
                        MaskComet(ROIsegm==0)=0;
                    end
                end

                ROIcomposed = falseColorsComet(ROIori, logical(MaskHead), logical(MaskComet), flag_CurrentCometType);

                app.scope.ImageSource = uint8(ROIcomposed);


                app.comet_handles.MaskComet = MaskComet;
                app.comet_handles.MaskHead = MaskHead;
            else
                % Set slider
                HeadThAddFactor = app.comet_handles.HeadThAddFactor;
                sliderValue_0_1 = 0.5 + HeadThAddFactor/(255*2);
                set(app.slider_head_threshold,'Value', sliderValue_0_1);
            end
        end

        % Button pushed function: text_DilationHead
        function text_DilationHead_Callback(app, event)
            flag_CurrentCometType = app.comet_handles.flag_CurrentCometType;

            if app.comet_handles.ROIshown == 1 && ~isempty(flag_CurrentCometType) && flag_CurrentCometType==3
                MaskComet = app.comet_handles.MaskComet;
                HeadSizeDiskDilation = 0;
                if HeadSizeDiskDilation >= 0
                    app.comet_handles.HeadSizeDiskDilation = HeadSizeDiskDilation;
                    set(app.text_DilationHead,'Text', num2str(HeadSizeDiskDilation));

                    % Set slider
                    sliderValue_0_1 = HeadSizeDiskDilation/150;
                    set(app.slider_head_Dilation,'Value', sliderValue_0_1);

                    ROIori = app.comet_handles.ROIori;
                    ROIoriFiltered = app.comet_handles.ROIoriFiltered;

                    [MaskHead, ~] = segmentHead(ROIoriFiltered, MaskComet, app.comet_handles.HeadThAddFactor, app.comet_handles.HeadSizeDiskDilation, app.comet_handles.flag_ThresholdHeadMode);

                    % To delete external pixels in case of perfect fit.
                    flag_CometFitFreehand = app.comet_handles.flag_CometFitFreehand;
                    ROIsegm = app.comet_handles.ROIsegm;
                    if flag_CometFitFreehand == 1
                        if ~isempty(MaskHead)
                            MaskHead(ROIsegm==0)=0;
                        end
                        if ~isempty(MaskComet)
                            MaskComet(ROIsegm==0)=0;
                        end
                    end

                    ROIcomposed = falseColorsComet(ROIori, logical(MaskHead), logical(MaskComet), flag_CurrentCometType);

                    app.scope.ImageSource = uint8(ROIcomposed);


                    app.comet_handles.MaskComet = MaskComet;
                    app.comet_handles.MaskHead = MaskHead;
                end
            end
        end

        % Button pushed function: text_Dilation
        function text_Dilation_Callback(app, event)
            flag_CurrentCometType = app.comet_handles.flag_CurrentCometType;

            if app.comet_handles.ROIshown == 1 && ~isempty(flag_CurrentCometType)
                MaskHead = app.comet_handles.MaskHead;
                CometSizeDiskDilation = 0;
                if CometSizeDiskDilation >= 0
                    app.comet_handles.CometSizeDiskDilation = CometSizeDiskDilation;
                    set(app.text_Dilation,'Text', num2str(CometSizeDiskDilation));

                    % Set slider
                    sliderValue_0_1 = CometSizeDiskDilation/150;
                    set(app.slider_comet_Dilation,'Value', sliderValue_0_1);

                    ROIori = app.comet_handles.ROIori;
                    ROIoriFiltered = app.comet_handles.ROIoriFiltered;

                    % Segment comet
                    flag_CometFitFreehand = app.comet_handles.flag_CometFitFreehand;
                    if flag_CometFitFreehand == 1
                        [MaskComet, ~] = segmentComet(ROIoriFiltered, app.comet_handles.ROIsegm, 0, 0, 0);
                    else
                        [MaskComet, ~] = segmentComet(ROIoriFiltered, app.comet_handles.ROIsegm, app.comet_handles.CometThAddFactor, app.comet_handles.CometSizeDiskDilation, app.comet_handles.flag_ThresholdMode);
                    end
                    if flag_CurrentCometType == 1
                        MaskHead = MaskComet;
                    end

                    % To delete external pixels in case of perfect fit.
                    ROIsegm = app.comet_handles.ROIsegm;
                    if flag_CometFitFreehand == 1
                        if ~isempty(MaskHead)
                            MaskHead(ROIsegm==0)=0;
                        end
                        if ~isempty(MaskComet)
                            MaskComet(ROIsegm==0)=0;
                        end
                    end

                    ROIcomposed = falseColorsComet(ROIori, logical(MaskHead), logical(MaskComet), flag_CurrentCometType);

                    app.scope.ImageSource = uint8(ROIcomposed);


                    app.comet_handles.MaskComet = MaskComet;
                    app.comet_handles.MaskHead = MaskHead;
                end
            end
        end

        % Button pushed function: text_ThresholdHead
        function text_ThresholdHead_Callback(app, event)
            flag_CurrentCometType = app.comet_handles.flag_CurrentCometType;

            if app.comet_handles.ROIshown == 1 && ~isempty(flag_CurrentCometType) && flag_CurrentCometType==3
                MaskComet = app.comet_handles.MaskComet;
                HeadThAddFactor = 0;
                app.comet_handles.HeadThAddFactor = HeadThAddFactor;
                set(app.text_ThresholdHead,'Text', num2str(HeadThAddFactor));

                % Set slider
                sliderValue_0_1 = 0.5 + HeadThAddFactor/(255*2);
                set(app.slider_head_threshold,'Value', sliderValue_0_1);

                ROIori = app.comet_handles.ROIori;
                ROIoriFiltered = app.comet_handles.ROIoriFiltered;

                [MaskHead, ~] = segmentHead(ROIoriFiltered, MaskComet, app.comet_handles.HeadThAddFactor, app.comet_handles.HeadSizeDiskDilation, app.comet_handles.flag_ThresholdHeadMode);

                % To delete external pixels in case of perfect fit.
                flag_CometFitFreehand = app.comet_handles.flag_CometFitFreehand;
                ROIsegm = app.comet_handles.ROIsegm;
                if flag_CometFitFreehand == 1
                    if ~isempty(MaskHead)
                        MaskHead(ROIsegm==0)=0;
                    end
                    if ~isempty(MaskComet)
                        MaskComet(ROIsegm==0)=0;
                    end
                end

                ROIcomposed = falseColorsComet(ROIori, logical(MaskHead), logical(MaskComet), flag_CurrentCometType);

                app.scope.ImageSource = uint8(ROIcomposed);


                app.comet_handles.MaskComet = MaskComet;
                app.comet_handles.MaskHead = MaskHead;
            end
        end

        % Button pushed function: text_Threshold
        function text_Threshold_Callback(app, event)
            flag_CurrentCometType = app.comet_handles.flag_CurrentCometType;

            if app.comet_handles.ROIshown == 1 && ~isempty(flag_CurrentCometType)
                MaskHead = app.comet_handles.MaskHead;
                CometThAddFactor = 0;
                app.comet_handles.CometThAddFactor = CometThAddFactor;
                set(app.text_Threshold,'Text', num2str(CometThAddFactor));

                % Set slider
                sliderValue_0_1 = 0.5 + CometThAddFactor/(255*2);
                set(app.slider_comet_threshold,'Value', sliderValue_0_1);

                ROIori = app.comet_handles.ROIori;
                ROIoriFiltered = app.comet_handles.ROIoriFiltered;

                % Segment comet
                flag_CometFitFreehand = app.comet_handles.flag_CometFitFreehand;
                if flag_CometFitFreehand == 1
                    [MaskComet, ~] = segmentComet(ROIoriFiltered, app.comet_handles.ROIsegm, 0, 0, 0);
                else
                    [MaskComet, ~] = segmentComet(ROIoriFiltered, app.comet_handles.ROIsegm, app.comet_handles.CometThAddFactor, app.comet_handles.CometSizeDiskDilation, app.comet_handles.flag_ThresholdMode);
                end
                if flag_CurrentCometType == 1
                    MaskHead = MaskComet;
                end

                % To delete external pixels in case of perfect fit.
                ROIsegm = app.comet_handles.ROIsegm;
                if flag_CometFitFreehand == 1
                    if ~isempty(MaskHead)
                        MaskHead(ROIsegm==0)=0;
                    end
                    if ~isempty(MaskComet)
                        MaskComet(ROIsegm==0)=0;
                    end
                end

                ROIcomposed = falseColorsComet(ROIori, logical(MaskHead), logical(MaskComet), flag_CurrentCometType);

                app.scope.ImageSource = uint8(ROIcomposed);


                app.comet_handles.MaskComet = MaskComet;
                app.comet_handles.MaskHead = MaskHead;
            end
        end

        % Key press function: figure1
        function KeyPressFcn(app, event)
            key = event.Key;

            switch key
                case 'q'
                    InterfaceObj=findobj(app.figure1,'Enable','off');
                    set(InterfaceObj, 'Enable', 'on');
                    app.calculating = 0;
                    app.comet_handles.SelectACometOngoing = 0;
                    %                 case 'a'
                    %                     app.but_CometAdd_Callback(app)
                    %
                    %                 case 's'
                    %                     app.but_SelectComet_Callback(app)
                    %
                    %                 case 'y'
                    %                     pause(1)
                    %                     [~, ~] = createUnclassifiedClassForGreens(app);
                    %                     if app.comet_handles.ClassCounter > 0
                    %                         answ = questdlg('Do you want to save training data for YOLO training?','YOLO trSet export',{'Yes','No','No'});
                    %                         if strcmp(answ,'Yes')
                    %                             indx = listdlg('PromptString', 'Select class to save', 'ListString', app.pop_class.Items, 'SelectionMode', 'single');
                    %                             imFolderPath = app.comet_handles.dirList(1).folder;
                    %                             calcYoloInputCoordinates(app.comet_handles.Classes.(app.pop_class.Items{indx}).Members, imFolderPath)
                    %                         end
                    %                     end
                otherwise
            end
        end

        % Callback function
        function Menu_Annotation_StandardSelected(app, event)
            event.Source.Checked = 'on';
            app.Menu_Annotation_Assisted.Checked = 'off';
        end

        % Callback function
        function Menu_Annotation_AssistedSelected(app, event)
            event.Source.Checked = 'on';
            app.Menu_Annotation_Standard.Checked = 'off';
        end

        % Button pushed function: ClassBrowserButton
        function ClassBrowserButtonPushed(app, event)
            if app.comet_handles.ClassCounter
                app.scope.ImageSource = app.CometIcon;
                if isempty(app.classBrowser_handle)
                    app.classBrowser_handle = classBrowser(app);
                else
                    app.classBrowser_handle.Class_Browser.Visible = 'off';
                    app.classBrowser_handle.Class_Browser.Visible = 'on';
                end
            else
                message = {'There is no class.'};
                appTextDlg(app, message, 'Message', 'help')
            end
        end

        % Window button motion function: figure1
        function figure1WindowButtonMotion(app, event)
            if ~app.calculating
                if ~isempty(app.comet_handles.IndImgShown)
                    coor = round(app.axes1.CurrentPoint(1,1:2));
                    if isempty(app.delay) || (now - app.delay) > 5e-06
                        app.delay = [];
                        if coor(1) > 0 && coor(1) < app.comet_handles.ImageSize(2) && ...
                                coor(2) > 0 && coor(2) < app.comet_handles.ImageSize(1)
                            if app.comet_handles.Imgs_Stretched(coor(2),coor(1),2,app.comet_handles.IndImgShown) || app.comet_handles.Imgs_Stretched(coor(2),coor(1),3,app.comet_handles.IndImgShown)
                                cometIdxTail = app.comet_handles.Imgs_Stretched(coor(2),coor(1),2,app.comet_handles.IndImgShown);
                                if cometIdxTail < 255 % Classified (red and blue)
                                    classNames = fieldnames(app.comet_handles.Classes);
                                    classIdx = [];
                                    for i = 1:numel(classNames)
                                        if ~isempty(app.comet_handles.Classes.(classNames{i}).Members)
                                            imFileter = strcmp({app.comet_handles.Classes.(classNames{i}).Members.ImName},app.comet_handles.ImgsNames{app.comet_handles.IndImgShown});
                                            cometIDFilter = [app.comet_handles.Classes.(classNames{i}).Members.cometID] == cometIdxTail;
                                            if any(imFileter & cometIDFilter)
                                                classIdx = i;
                                                break
                                            end
                                        end
                                    end

                                    if ~isempty(classIdx)
                                        selectedName = classNames{classIdx};
                                    else % Something went wrong, no matching data in class
                                        selectedName = '#Error';
                                    end
                                else % Predicted (green and magenta)
                                    selectedName = 'Prediction';
                                end

                                if isempty(app.imDatatipText)
                                    if coor(1) > app.comet_handles.ImageSize(2)/2
                                        xSpecing = -20 - 12 * length(selectedName);
                                    else
                                        xSpecing = 30;
                                    end
                                    if coor(2) > app.comet_handles.ImageSize(1)/2
                                        ySpecing = -30;
                                    else
                                        ySpecing = 30;
                                    end
                                    app.imDatatipText = text(app.axes1,coor(1)+xSpecing,coor(2)+ySpecing,selectedName,...
                                        'Color','red','FontSize',14, 'FontWeight', 'bold', 'Interpreter', 'none');
                                    app.imDatatipText.BackgroundColor = [1 1 1];
                                end
                            else
                                delete(app.imDatatipText)
                                app.imDatatipText = [];
                            end
                        else
                            delete(app.imDatatipText)
                            app.imDatatipText = [];
                        end
                    end
                end
            end
        end

        % Window button down function: figure1
        function figure1WindowButtonDown(app, event)
            if ~isempty(app.comet_handles.IndImgShown) && app.comet_handles.IndImgShown && ~app.calculating && ~app.comet_handles.SelectACometOngoing
                app.calculating = 1;
                coor = round(app.axes1.CurrentPoint(1,1:2));
                if coor(1) > 0 && coor(1) < app.comet_handles.ImageSize(2) && coor(2) > 0 && coor(2) < app.comet_handles.ImageSize(1)
                    if app.comet_handles.Imgs_Stretched(coor(2),coor(1),2,app.comet_handles.IndImgShown) > 0
                        [iscomplete, errorString] = clickOnCometSelection(app, coor);
                        if iscomplete == 0
                            appTextDlg(app, errorString, 'Corrupted Class structure', 'error')
                            app.selectedComet = [];
                            app.scope.ImageSource = app.CometIcon;
                            app.comet_handles.ROIshown = 0;
                            app.comet_handles.ROIori = [];
                            app.comet_handles.ROIoriFiltered = [];
                            app.comet_handles.ROIsegm = [];
                            app.comet_handles.MaskComet = [];
                            app.comet_handles.MaskHead = [];
                            app.comet_handles.ROI_ULCyx_DRCyx = [];
                            app.axes1.Children(end).CData = composeImage(app.comet_handles.Imgs_Stretched(:,:,:,app.comet_handles.IndImgShown));
                        end
                    else
                        app.selectedComet = [];
                        app.scope.ImageSource = app.CometIcon;
                        app.comet_handles.ROIshown = 0;
                        app.comet_handles.ROIori = [];
                        app.comet_handles.ROIoriFiltered = [];
                        app.comet_handles.ROIsegm = [];
                        app.comet_handles.MaskComet = [];
                        app.comet_handles.MaskHead = [];
                        app.comet_handles.ROI_ULCyx_DRCyx = [];
                    end
                end
                app.calculating = 0;
            end

        end

        % Button pushed function: Class_Create_Button
        function Class_Create_ButtonPushed(app, event)
            if ~isempty(app.comet_handles.NumImages) && app.comet_handles.NumImages && ~app.calculating
                app.calculating = 1;
                tempNum = numel(app.pop_class.Items);
                tempValue = app.pop_class.Value;
                InterfaceObj=findobj(app.figure1,'Enable','on');
                set(InterfaceObj,'Enable','off');
                app.classNameInputHandle = classNameInput(app,[]);
                set(InterfaceObj,'Enable','on');
                if numel(app.pop_class.Items) > tempNum || ~strcmp(tempValue,app.pop_class.Value)
                    pop_class_Callback(app)
                    if app.classCreationSuccessor && ~isempty(app.classBrowser_handle)
                        app.classBrowser_handle.addClassToCatalog(app.pop_class.Value)
                    end
                end
                app.classNameInputHandle = [];
                app.calculating = 0;
            end
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            if ~isempty(app.classBrowser_handle)
                close(app.classBrowser_handle.Class_Browser)
            end
            delete(app)
        end

        % Button pushed function: Class_Remove_Button
        function Class_Remove_ButtonPushed(app, event)
            Classes = app.comet_handles.Classes;
            if ~isempty(Classes)
                answer = questdlg('Do you want to delete the selected class?','Remove Class','Yes','No','No');
                if ~isempty(answer) && strcmp(answer,'Yes')
                    ImgsNames = app.comet_handles.ImgsNames;
                    numOfMembers = size(app.comet_handles.Classes.(app.pop_class.Value).Members,2);
                    if numOfMembers > 0
                        for i = numOfMembers:-1:1
                            app.selectedComet.className = app.pop_class.Value;
                            app.selectedComet.param = app.comet_handles.Classes.(app.selectedComet.className).Members(i);
                            imID = find(strcmp(ImgsNames,app.selectedComet.param.ImName));
                            app.comet_handles.IndImgShown = imID;
                            app.axes1.Children.CData = composeImage(app.comet_handles.Imgs_Stretched(:,:,:,app.comet_handles.IndImgShown));
                            app.but_CometRemove_Callback(app);
                        end
                        app.selectedComet = [];
                    end
                    app.comet_handles.Classes = rmfield(app.comet_handles.Classes,app.pop_class.Value);
                    if length(app.pop_class.Items) == 1
                        app.pop_class.Items = {'~No Class~'};
                        app.pop_class.Value = '~No Class~';
                        app.comet_handles.ClassID = 0;
                        app.comet_handles.ClassCounter = 0;
                        if ~isempty(app.classBrowser_handle)
                            close(app.classBrowser_handle.Class_Browser)
                        end
                    else
                        ID2delete = strcmp(app.pop_class.Items,app.pop_class.Value);
                        if ~isempty(app.classBrowser_handle)
                            app.classBrowser_handle.removeClassFromCatalog(app.pop_class.Value)
                        end
                        app.pop_class.Items(ID2delete) = [];
                        app.pop_class.Value = app.pop_class.Items{1};
                        pop_class_Callback(app)
                        app.comet_handles.ClassCounter = app.comet_handles.ClassCounter - 1;
                    end
                end
            end
        end

        % Menu selected function: ImageSegmentationMenu
        function ImageSegmentationMenuSelected(app, event)
            if ~isempty(app.comet_handles.IndImgShown) && app.comet_handles.IndImgShown && ~app.calculating && ~app.comet_handles.SelectACometOngoing
                app.calculating = 1;
                [bool, message] = predictImageSegmentation(app,'single');
                if bool
                    app.SegmentationBool = 1;
                end
                appTextDlg(app, message, 'Message', 'warn')
                app.axes1.Children.CData = composeImage(app.comet_handles.Imgs_Stretched(:,:,:,app.comet_handles.IndImgShown));
                app.calculating = 0;
            end
        end

        % Menu selected function: TrainNewModelMenu
        function TrainNewModelMenuSelected(app, event)
            if ~app.calculating && ~app.comet_handles.SelectACometOngoing
                app.calculating = 1;
                if exist("deeplabv3plusLayers",'file') || exist("resnet18",'file')
                    path = uigetdir('','Select the folder containing the annotated training data');
                    if ischar(path)
                        [bool, message] = trainSemanticSegmentationModel(path,app.comet_handles.segmentationOptions);
                        if bool == 0
                            appTextDlg(app, message, 'Message', 'warn')
                        end
                    end
                else
                    message = {'Missing file(s)!';...
                                'Make sure that you have downloaded "deeplabv3plusLayers" and "resnet18".';
                                'For help, read the help menu!'};
                    appTextDlg(app, message, 'Message', 'warn')
                end
                app.calculating = 0;
            end
        end

        % Menu selected function: OptionsMenu_Segmentation
        function OptionsMenu_SegmentationSelected(app, event)
            if ~app.calculating && ~app.comet_handles.SelectACometOngoing
                app.calculating = 1;
                app.segmentationTrainingOptions_handle = segmentationOptions(app);
                waitfor(app.segmentationTrainingOptions_handle)
                app.calculating = 0;
            end
        end

        % Menu selected function: ExportAnnotationsMenu
        function ExportAnnotationsMenuSelected(app, event)
            if ~isempty(app.comet_handles.IndImgShown) && app.comet_handles.IndImgShown && ~app.calculating && ~app.comet_handles.SelectACometOngoing
                app.calculating = 1;
                path = uigetdir('','Select folder to save annotated data!');
                if ischar(path)
                    [bool, message] = exportAnnotation(app, path);
                    if bool == 0
                        appTextDlg(app, message, 'Message', 'warn')
                    end
                end
                app.calculating = 0;
            end
        end

        % Menu selected function: DatasetSegmentationMenu
        function DatasetSegmentationMenuSelected(app, event)
            if ~isempty(app.comet_handles.IndImgShown) && app.comet_handles.IndImgShown && ~app.calculating && ~app.comet_handles.SelectACometOngoing
                app.calculating = 1;
                [bool, message] = predictImageSegmentation(app,'multi');
                if bool == 0
                    appTextDlg(app, message, 'Message', 'warn')
                else
                    appTextDlg(app, message, 'Message', 'help')
                    app.SegmentationBool = 1;
                end
                app.axes1.Children.CData = composeImage(app.comet_handles.Imgs_Stretched(:,:,:,app.comet_handles.IndImgShown));
                app.calculating = 0;
            end
        end

        % Menu selected function: ClearSegmentationsMenu
        function ClearSegmentationsMenuSelected(app, event)
            if ~isempty(app.comet_handles.IndImgShown) && app.comet_handles.IndImgShown && ~app.calculating && ~app.comet_handles.SelectACometOngoing
                app.calculating = 1;
                cometMaskLayer = app.comet_handles.Imgs_Stretched(:,:,2,:);
                cometHeadLayer = app.comet_handles.Imgs_Stretched(:,:,3,:);
                cometMaskLayer(cometMaskLayer==255) = 0;
                cometHeadLayer(cometHeadLayer==255) = 0;
                app.comet_handles.Imgs_Stretched(:,:,2,:) = cometMaskLayer;
                app.comet_handles.Imgs_Stretched(:,:,3,:) = cometHeadLayer;
                app.SegmentationBool = 0;
                app.axes1.Children.CData = composeImage(app.comet_handles.Imgs_Stretched(:,:,:,app.comet_handles.IndImgShown));
                app.calculating = 0;
            end
        end

        % Menu selected function: 
        % SavesegmentationstoUnclassifiedclassMenu
        function SavesegmentationstoUnclassifiedclassMenuSelected(app, event)
            if any(app.comet_handles.Imgs_Stretched(:,:,2,:) == 255, 'all')
                if ~app.calculating && ~app.comet_handles.SelectACometOngoing
                    app.calculating = 1;
                    if strcmp(app.pop_class.Items{1},'~No Class~')
                        app.pop_class.Items = {'Unclassified'};
                        app.comet_handles.ClassCounter = app.comet_handles.ClassCounter + 1;
                        app.comet_handles.Classes.('Unclassified').Members = [];
                        app.comet_handles.Classes.('Unclassified').ID = app.comet_handles.ClassCounter;
                    elseif ~any(strcmp(app.pop_class.Items,'Unclassified'))
                        app.pop_class.Items = [app.pop_class.Items, 'Unclassified'];
                        app.comet_handles.ClassCounter = app.comet_handles.ClassCounter + 1;
                        app.comet_handles.Classes.('Unclassified').Members = [];
                        app.comet_handles.Classes.('Unclassified').ID = app.comet_handles.ClassCounter;
                    end
                    app.pop_class.Value = 'Unclassified';
                    app.pop_class_Callback(app)
                    [bool, message] = createUnclassifiedClassForGreens(app);
                    if bool == 0
                        appTextDlg(app, message, 'Message', 'warn')
                    end
                    if ~isempty(app.classBrowser_handle) && ishandle(app.classBrowser_handle.Class_Browser)
                        close(app.classBrowser_handle.Class_Browser)
                    end
                    app.calculating = 0;
                end
            else
                message = {'Missing segmentation. Perform automatic segmentation first.'};
                appTextDlg(app, message, 'Message', 'warn')
            end
        end

        % Callback function
        function SetmetadatapathMenuSelected(app, event)
            [file, path] = uigetfile('.csv','Select meta data file!');
            app.metaDataPath = fullfile(path,file);
        end

        % Menu selected function: ClassificationTrainingOptionsMenu
        function ClassificationTrainingOptionsMenuSelected(app, event)
            if ~isempty(app.comet_handles.IndImgShown) && app.comet_handles.IndImgShown && ~app.calculating && ~app.comet_handles.SelectACometOngoing && app.calculating == 0
                app.calculating = 1;
                app.classificationTrainingOptions_handle = classificationOptions(app);
                waitfor(app.classificationTrainingOptions_handle);
                if ~isempty(app.classificationModelPath) && ~strcmp(app.classificationModelPath,'Empty')...
                        &&  length(app.classificationModelPath)>3 ...
                        && strcmp(app.classificationModelPath(end-3:end),'.mat')
                    try
                        load(app.classificationModelPath);
                        app.ClassifierModel = modelToSave;
                    catch
                        app.ClassifierModel = [];
                        errorString = {'Loading of pretrained model terminated. Selected file is corrupted.'};
                        appTextDlg(app, errorString, 'Message', 'error')
                        app.calculating = 0;
                        return
                    end
                    classNames = setdiff(fieldnames(app.comet_handles.Classes),'Unclassified');
                    if ~isprop(app.ClassifierModel,'ClassNames') && ~isempty(setdiff(modelToSave.ClassNames,classNames))
                        app.ClassifierModel = [];
                        errorString = ['Required class name does not exist in this project. Make sure that you created all the classes';''; 'Required Class names:'; modelToSave.ClassNames];
                        appTextDlg(app, errorString, 'Message', 'error')
                        app.calculating = 0;
                        return
                    end
                end
                app.calculating = 0;
            end
        end

        % Menu selected function: TrainClassifierMenu
        function TrainClassifierMenuSelected(app, event)
            if ~isempty(app.comet_handles.IndImgShown) && app.comet_handles.IndImgShown && ~app.calculating && ~app.comet_handles.SelectACometOngoing
                app.calculating = 1;
                app.calculating
                if ~isempty(app.ClassifierModel)
                    warnText = sprintf(['Trained model already exist.\n Would you like to train a new model?\n',...
                        'The process rewrittes the previous model.\n Any unsaved data will be lost.']);
                    questAnswer = questdlg(warnText,'Re-train',{'Yes','No','No'});
                    if ~strcmp(questAnswer,'Yes')
                        app.calculating = 0;
                        return
                    end
                end
                if isempty(app.metaDataPath) || strcmp(app.metaDataPath,'Empty')
                    helpdlg(sprintf(['Missing measurements.\n',...
                        'Please export them using the Analysis menu.']))
                    app.calculating = 0;
                    return
                end

                if isempty(app.classificationMethod)
                    helpdlg('Missing classification method. Please set training options.')
                    app.calculating = 0;
                    return
                end
                featureTable = readtable(app.metaDataPath);
                classNames = setdiff(fields(app.comet_handles.Classes),'Unclassified');
                if ~isempty(classNames)
                    idx = contains(featureTable.CometClass,classNames);
                    filteredFeatureTable = featureTable(idx,:);
                    X = filteredFeatureTable(:,10:end);
                    Y = filteredFeatureTable.CometClass;
                    switch app.classificationMethod
                        case 'Decision tree'
                            app.ClassifierModel = fitctree(X,Y,'OptimizeHyperparameters','auto');
                        case 'k Nearest Neighbours'
                            app.ClassifierModel = fitcknn(X,Y,'OptimizeHyperparameters','auto','HyperparameterOptimizationOptions',...
                                struct('AcquisitionFunctionName','expected-improvement-plus'));
                        case 'Naive Bayes'
                            app.ClassifierModel = fitcnb(X,Y,'ClassNames',classNames,'OptimizeHyperparameters','auto',...
                                'HyperparameterOptimizationOptions',struct('AcquisitionFunctionName',...
                                'expected-improvement-plus'));
                        case 'Support Vector Machine'
                            app.ClassifierModel = fitcecoc(X,Y,'OptimizeHyperparameters','auto', ...
                                'HyperparameterOptimizationOptions',struct('AcquisitionFunctionName', ...
                                'expected-improvement-plus'));
                        otherwise
                    end
                    app.calculating = 0;
                    app.calculating
                    helpdlg('Classifier trained.')
                else
                    app.calculating = 0;
                    warndlg('Measurement file doesn not contain user-defined classes to train a classifier.')
                end
            end
        end

        % Menu selected function: PredictionMenu
        function PredictionMenuSelected(app, event)
            if ~isempty(app.ClassifierModel)
                if isfield(app.comet_handles.Classes,'Unclassified') && size(app.comet_handles.Classes.Unclassified.Members,2) > 0
                    if strcmp(app.PredictionMenu.Checked,'off')
                        app.PredictionMenu.Checked = 'on';
                        features = readtable(app.metaDataPath);
                        if strcmp(features.ImageName(1),'Measurement Identifier:') && features.CometID(1) == app.comet_handles.uniqueIdentifier
                            if any(strcmp({app.comet_handles.Classes.Unclassified.Members.ImName},app.comet_handles.ImgsNames(app.comet_handles.IndImgShown)))
                                idx = logical(strcmp(features.ImageName,app.comet_handles.ImgsNames(app.comet_handles.IndImgShown)) .* strcmp(features.CometClass,'Unclassified'));
                                if any(idx)
                                    switch app.ClassifierModel.ModelParameters.Method
                                        case 'Tree'
                                            [cLabel,score,~,cnum] = predict(app.ClassifierModel,features{idx,10:end});

                                        case 'KNN'
                                            [cLabel,score,~] = predict(app.ClassifierModel,features{idx,10:end});
                                            [cnum,~] = find(score');

                                        case 'NaiveBayes'
                                            [cLabel,Posterior,~] = predict(app.ClassifierModel,features{idx,10:end}); % default output [label,Posterior,Cost]
                                            score = Posterior;
                                            [cnum,~] = find(round(Posterior'));

                                        case 'ECOC'
                                            [cLabel,~,score] = predict(app.ClassifierModel,features{idx,10:end});
                                            [cnum,~] = find((score == max(score,[],2))');
                                        otherwise
                                    end
                                    idxBB = strcmp({app.comet_handles.Classes.Unclassified.Members.ImName},app.comet_handles.ImgsNames{app.comet_handles.IndImgShown});
                                    cometIDs = [app.comet_handles.Classes.Unclassified.Members(idxBB).cometID];
                                    classNames = app.ClassifierModel.ClassNames;
                                    tempIm = classFilteredImage(composeImage(app.comet_handles.Imgs_Stretched(:,:,:,app.comet_handles.IndImgShown)),...
                                        app.comet_handles.Imgs_Stretched(:,:,2,app.comet_handles.IndImgShown),...
                                        cLabel, score, cnum, classNames, cometIDs);
                                    app.axes1.Children.CData = uint8(tempIm);
                                else
                                    error = {'No matching file name in the measurement file.'};
                                    appTextDlg(app, error, 'Message', 'error')
                                    app.PredictionMenu.Checked = 'off';
                                end
                            else
                                error = {'No matching file name in the measurement file.';
                                    'Make sure to have ''Unclassified'' comets on this image.'};
                                appTextDlg(app, error, 'Message', 'error')
                                app.PredictionMenu.Checked = 'off';
                            end
                        else
                            error = {'The loaded measurement file is outdated.';
                                'Post-measurement modifications has been detected. ';
                                'Please calculate measurements again.'};
                            appTextDlg(app, error, 'Message', 'error')
                            app.PredictionMenu.Checked = 'off';
                        end
                    else
                        app.PredictionMenu.Checked = 'off';
                        app.axes1.Children.CData = composeImage(app.comet_handles.Imgs_Stretched(:,:,:,app.comet_handles.IndImgShown));
                    end
                else
                    app.PredictionMenu.Checked = 'off';
                    helpMsg = {'Prediction works only with the ''Unclassified'' class.';...
                        'Please create it and fill it up with the oubjects you would like to classify.'};
                    appTextDlg(app, helpMsg, 'Message', 'help')
                end
            else
                app.PredictionMenu.Checked = 'off';
                helpMsg = {'Please train or load a classifier first.'};
                appTextDlg(app, helpMsg, 'Message', 'help')
            end
        end

        % Menu selected function: ClassificationMenu_2
        function ClassificationMenu_2Selected(app, event)
            if ~app.calculating
                app.calculating = 1;
                if ~isempty(app.ClassifierModel)
                    if strcmp(app.PredictionMenu.Checked,'on')
                        PredictionMenuSelected(app)
                    end

                    features = readtable(app.metaDataPath);
                    if strcmp(features.ImageName(1),'Measurement Identifier:') && features.CometID(1) == app.comet_handles.uniqueIdentifier
                        
                    hlpString = {'Before classification please save your projects.'};
                    appTextDlg(app, hlpString, 'Message', 'help')

                    Menu_File_SaveData_Callback(app)
                    missingClassNames = setdiff(app.ClassifierModel.ClassNames,fieldnames(app.comet_handles.Classes));

                    if ~isempty(missingClassNames)
                        message = ['According to the pre-trained clessifier the following classes are missing in this project:';...
                            '';...
                            missingClassNames;...
                            '';...
                            'Would you like to create missing classes?'];
                        answer = questdlg(message,'Missing classes','Yes','No','Yes');
                        if strcmp(answer,'Yes')
                            for i = 1:numel(missingClassNames)
                                app.comet_handles.ClassCounter = app.comet_handles.ClassCounter + 1;
                                app.comet_handles.Classes.(missingClassNames{i}).Members = [];
                                app.comet_handles.Classes.(missingClassNames{i}).ID = app.comet_handles.ClassCounter;
                            end
                            app.pop_class.Items = fieldnames(app.comet_handles.Classes);
                            helpdlg('Missing classes have been created.','Class creation')
                        else
                            helpdlg('Classification terminated.')
                            app.calculating = 0;
                            return
                        end
                    end
                    if strcmp(app.ClassifierModel.ModelParameters.Method,'ECOC')
                        classifierThershold = 0;
                        prompt = sprintf('Enter confidence threshold for classification: \n (numerical value >= 0)');
                    else
                        classifierThershold = 0.5;
                        prompt = sprintf('Enter confidence threshold for classification: \n (numerical value between 0.5 and 1)');
                    end

                    userThershold = classifierThershold-1;
                    while userThershold < classifierThershold
                        dlgtitle = 'Input threshold';
                        dims = 1;
                        answer = inputdlg(prompt,dlgtitle,dims,{num2str(classifierThershold)});
                        if ~isempty(answer)
                            answer = str2double(answer{1});
                            if isnan(answer)
                                warnString = {'Threshold must be numeric between 0.5 and 1!'};
                                appTextDlg(app, warnString, 'Message', 'warn')
                            else
                                if answer < 0.5 || answer > 1
                                    warnString = {'Threshold must be numeric between 0.5 and 1!'};
                                    appTextDlg(app, warnString, 'Message', 'warn')
                                else
                                    userThershold = answer;
                                end
                            end
                        else
                            app.calculating = 0;
                            return
                        end
                    end


                        app.comet_handles.IndImgShown = app.comet_handles.NumImages;
                        wb = waitbar(0,'Classification is running. Please wait...');
                        for i = 1:app.comet_handles.NumImages
                            pause(0.1)
                            but_Right_Callback(app)
                            pause(0.1)
                            if ishandle(wb)
                                waitbar(i/app.comet_handles.NumImages,wb,'Classification is running. Please wait...');
                            end
                            idx = logical(strcmp(features.ImageName,app.comet_handles.ImgsNames(i)) .* strcmp(features.CometClass,'Unclassified'));
                            switch app.ClassifierModel.ModelParameters.Method
                                case 'Tree'
                                    [cLabel,score,~,cnum] = predict(app.ClassifierModel,features{idx,10:end});

                                case 'KNN'
                                    [cLabel,score,~] = predict(app.ClassifierModel,features{idx,10:end});
                                    [cnum,~] = find(score');

                                case 'NaiveBayes'
                                    [cLabel,Posterior,~] = predict(app.ClassifierModel,features{idx,10:end});
                                    score = Posterior;
                                    [cnum,~] = find(round(Posterior'));

                                case 'ECOC'
                                    [cLabel,~,score] = predict(app.ClassifierModel,features{idx,10:end});
                                    [cnum,~] = find((score == max(score,[],2))');

                                otherwise
                            end

                            cometIDs = features.CometID(idx);
                            idx2 = find(idx);
                            for j = 1:sum(idx)
                                if score(j,cnum(j)) >= userThershold
                                    coor(2) = features{idx2(j),4};
                                    coor(1) = features{idx2(j),5};
                                    if cometIDs(j) ~= app.comet_handles.Imgs_Stretched(coor(2),coor(1),2,i)
                                        errorMsg = {'A missmatch detected between the project and the measurement file on image:';...
                                            app.comet_handles.ImgsNames{i};...
                                            'Comet ID:';...
                                            num2str(cometIDs(j))};
                                        appTextDlg(app, errorMsg, 'Message', 'warn')
                                    end
                                    [iscomplete, errorString] = clickOnCometSelection(app, coor);
                                    if iscomplete
                                        if strcmp(app.selectedComet.className,'Unclassified')
                                            [bool, warnString] = addComet(app,cLabel{j});
                                            if bool == 0
                                                appTextDlg(app, warnString, 'Message', 'error')
                                                app.calculating = 0;
                                                if ishandle(wb), close(wb), end
                                                return
                                            end
                                        end
                                    else
                                        appTextDlg(app, errorString, 'Message', 'error')
                                        app.calculating = 0;
                                        if ishandle(wb), close(wb), end
                                        return
                                    end
                                end
                            end
                        end
                    else
                        error = {'The loaded measurement file is outdated.';
                            'Post-measurement modifications has been detected. ';
                            'Please calculate measurements again.'};
                        appTextDlg(app, error, 'Message', 'error')
                    end
                    if ishandle(wb), close(wb), end

                    if ~isempty(app.classBrowser_handle) && ishandle(app.classBrowser_handle.Class_Browser)
                        close(app.classBrowser_handle.Class_Browser)
                    end
                    helpdlg('Classification completed.')
                    app.calculating = 0;
                else
                    warnString = {'Train a classifier first!'};
                    appTextDlg(app, warnString, 'Message', 'warn')
                    app.calculating = 0;
                end
            end
        end

        % Menu selected function: ExportClassifierMenu
        function ExportClassifierMenuSelected(app, event)
            if ~isempty(app.ClassifierModel)
                [fileName, path] = uiputfile('.mat','Save trained classifier model',['Trained_',app.ClassifierModel.ModelParameters.Method,'_Classifier']);
                if fileName ~= 0
                    modelToSave = app.ClassifierModel;
                    save(fullfile(path,fileName),'modelToSave')
                end
            else
                warnString = {'Train a classifier first!'};
                appTextDlg(app, warnString, 'Message', 'warn')
            end
            helpdlg('Model saved.')
        end

        % Menu selected function: DocumentationMenu
        function DocumentationMenuSelected(app, event)
            helpString = {'Please, read the User Manual provided with the tool as a PDF file'};
            appTextDlg(app, helpString, 'Message', 'help')
        end

        % Menu selected function: ClearAnnotationsMenu
        function ClearAnnotationsMenuSelected(app, event)
            if ~isempty(app.comet_handles.IndImgShown) && app.comet_handles.IndImgShown && ~app.calculating && ~app.comet_handles.SelectACometOngoing
                app.calculating = 1;
                clearAnnotationFunction(app)
                app.axes1.Children.CData = composeImage(app.comet_handles.Imgs_Stretched(:,:,:,app.comet_handles.IndImgShown));
                app.calculating = 0;
            end
        end

        % Menu selected function: BrightnessContrastMenu
        function BrightnessContrastMenuSelected(app, event)
            if ~isempty(app.comet_handles.IndImgShown) && app.comet_handles.IndImgShown && ~app.calculating && ~app.comet_handles.SelectACometOngoing
                app.calculating = 1;
                prevMinIntensity = app.newMinIntensity;
                prevMaxIntensity = app.newMaxIntensity;
                app.tempFig = figure;
                tempAx = axes(app.tempFig);
                imshow(app.comet_handles.Imgs_Ori(:,:,1,app.comet_handles.IndImgShown),'Parent',tempAx);
                app.imContrastHandles = imcontrast(app.tempFig);
                app.imContrastHandles.Children(2).Visible = 'off';
                app.imContrastHandles.Children(3).Visible = 'off';
                app.imContrastHandles.Children(4).Visible = 'off';
                if ~isdeployed
                    app.imContrastHandles.Children(5).Visible = 'off';
                end
                app.imContrastHandles.Children(1).Children(1).Children(1).Callback = app.imContrastHandles.CloseRequestFcn;
                app.imContrastHandles.Children(1).Children(1).Children(1).String = 'Apply';
                app.imContrastHandles.Children(1).Children(1).Children(1).Enable = 'on';
                maxEdit = findobj('Tag','window max edit');
                minEdit = findobj('Tag','window min edit');
                minEdit = minEdit(1);
                app.newMinIntensity = 0;
                app.newMaxIntensity = 255;
                addlistener(maxEdit,'String','PostSet',@(src,event)app.setMaxIntensity(maxEdit.String));
                addlistener(minEdit,'String','PostSet',@(src,event)app.setMinIntensity(minEdit.String));
                waitfor(app.imContrastHandles)
                close(app.tempFig);
                answer = questdlg('Would you like to update intensity values according to the test image?','Intensity adjustment','Yes','No','Yes');

                if strcmp(answer,'Yes')
                    app.comet_handles.IntensityMaxValStretched = app.newMaxIntensity;
                    app.comet_handles.IntensityMinValStretched = app.newMinIntensity;
                    a = double(0);   %Theoretically->double(0);
                    b = double(255); %Theoretically->double(255);
                    % Save the images
                    c = app.newMinIntensity;
                    d = app.newMaxIntensity;

                    % Modify the original images by stretching
                    Imgs_Stretched = app.comet_handles.Imgs_Ori;
                    Imgs_Stretched(Imgs_Stretched>d) = d;
                    Imgs_Stretched(Imgs_Stretched<c) = c;
                    Imgs_Stretched = ((Imgs_Stretched-c).*((b-a)/(d-c)))+a;
                    app.comet_handles.Imgs_Stretched(:,:,1,:) = Imgs_Stretched;
                else
                    app.newMinIntensity = prevMinIntensity;
                    app.newMaxIntensity = prevMaxIntensity;
                end
                app.axes1.Children.CData = composeImage(app.comet_handles.Imgs_Stretched(:,:,:,app.comet_handles.IndImgShown));
                app.calculating = 0;
            end
        end

        % Menu selected function: RemoveImageMenu_2
        function RemoveImageMenu_2Selected(app, event)
            if ~isempty(app.comet_handles.IndImgShown) && app.comet_handles.IndImgShown && ~app.calculating && ~app.comet_handles.SelectACometOngoing
                if app.comet_handles.NumImages > 1
                    app.calculating = 1;
                    answer = questdlg('Do you want to remove the current image and its annotations?','Image removal','Yes','No','No');
                    if strcmp(answer,'Yes')
                        clearAnnotationFunction(app)
                        IndImgShown = app.comet_handles.IndImgShown;
                        app.comet_handles.NumImages = app.comet_handles.NumImages-1;
                        app.comet_handles.Imgs_Ori(:,:,:,IndImgShown) = [];
                        app.comet_handles.Imgs_Stretched(:,:,:,IndImgShown) = [];
                        app.comet_handles.dirList(IndImgShown) = [];
                        app.comet_handles.ImgsNames(IndImgShown) = [];
                        if IndImgShown == 1
                            app.comet_handles.IndImgShown = 2;
                        end
                        but_Left_Callback(app, event)
                    end
                    app.calculating = 0;
                else
                    warnString = {'The last image cannot be removed.'};
                    appTextDlg(app, warnString, 'Message', 'warn')
                end
            end
        end

        % Menu selected function: OptionsMenu
        function OptionsMenuSelected(app, event)
            if ~isempty(app.comet_handles.IndImgShown) && app.comet_handles.IndImgShown && ~app.calculating && ~app.comet_handles.SelectACometOngoing
                app.calculating = 1;
                newValue = [];
                while isempty(newValue)
                    answer = inputdlg('Set the number of newly added comets are required to the software to ask for an quick save:\n The number must be numeric value between [0 to 100]','Auto-save frequency',1,"10");
                    if ~isempty(answer)
                        newValue = str2double(answer{1});
                        if newValue>=0 && newValue<=100
                            app.comet_handles.AutoSaveEveryNComets = newValue;
                        else
                            newValue = [];
                        end
                    else
                        helpString = {'No changes.'};
                        appTextDlg(app, helpString, 'Message', 'help')
                        newValue = 'end';
                    end
                end
                app.calculating = 0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'CometAnalyser_GUI';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.figure1.WindowButtonDownFcn = createCallbackFcn(app, @figure1WindowButtonDown, true);
            app.figure1.WindowButtonMotionFcn = createCallbackFcn(app, @figure1WindowButtonMotion, true);
            app.figure1.KeyPressFcn = createCallbackFcn(app, @KeyPressFcn, true);
            app.figure1.Tag = 'figure1';
            app.Menu_File = uimenu(app.figure1);
            app.Menu_File.Text = 'File';
            app.Menu_File.Tag = 'Menu_File';
            app.Menu_File_OpenS = uimenu(app.Menu_File);
            app.Menu_File_OpenS.MenuSelectedFcn = createCallbackFcn(app, @Menu_File_OpenS_Callback, true);
            app.Menu_File_OpenS.Text = 'Open (silver-stained images)';
            app.Menu_File_OpenS.Tag = 'Menu_File_OpenS';
            app.Menu_File_OpenF = uimenu(app.Menu_File);
            app.Menu_File_OpenF.MenuSelectedFcn = createCallbackFcn(app, @Menu_File_OpenF_Callback, true);
            app.Menu_File_OpenF.Text = 'Open (fluorescence images)';
            app.Menu_File_OpenF.Tag = 'Menu_File_OpenF';
            app.Menu_File_LoadData = uimenu(app.Menu_File);
            app.Menu_File_LoadData.MenuSelectedFcn = createCallbackFcn(app, @Menu_File_LoadData_Callback, true);
            app.Menu_File_LoadData.Separator = 'on';
            app.Menu_File_LoadData.Text = 'Load Project';
            app.Menu_File_LoadData.Tag = 'Menu_File_LoadData';
            app.Menu_File_SaveData = uimenu(app.Menu_File);
            app.Menu_File_SaveData.MenuSelectedFcn = createCallbackFcn(app, @Menu_File_SaveData_Callback, true);
            app.Menu_File_SaveData.Text = 'Save Project';
            app.Menu_File_SaveData.Tag = 'Menu_File_SaveData';
            app.RemoveImageMenu_2 = uimenu(app.Menu_File);
            app.RemoveImageMenu_2.MenuSelectedFcn = createCallbackFcn(app, @RemoveImageMenu_2Selected, true);
            app.RemoveImageMenu_2.Separator = 'on';
            app.RemoveImageMenu_2.Text = 'Remove Image';
            app.BrightnessContrastMenu = uimenu(app.Menu_File);
            app.BrightnessContrastMenu.MenuSelectedFcn = createCallbackFcn(app, @BrightnessContrastMenuSelected, true);
            app.BrightnessContrastMenu.Text = 'Brightness / Contrast';
            app.OptionsMenu = uimenu(app.Menu_File);
            app.OptionsMenu.MenuSelectedFcn = createCallbackFcn(app, @OptionsMenuSelected, true);
            app.OptionsMenu.Text = 'Options';
            app.Segmentation = uimenu(app.figure1);
            app.Segmentation.Text = 'Segmentation';
            app.ExportAnnotationsMenu = uimenu(app.Segmentation);
            app.ExportAnnotationsMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportAnnotationsMenuSelected, true);
            app.ExportAnnotationsMenu.Text = 'Export Annotations';
            app.TrainNewModelMenu = uimenu(app.Segmentation);
            app.TrainNewModelMenu.MenuSelectedFcn = createCallbackFcn(app, @TrainNewModelMenuSelected, true);
            app.TrainNewModelMenu.Text = 'Train New Model';
            app.ImageSegmentationMenu = uimenu(app.Segmentation);
            app.ImageSegmentationMenu.MenuSelectedFcn = createCallbackFcn(app, @ImageSegmentationMenuSelected, true);
            app.ImageSegmentationMenu.Text = 'Image Segmentation';
            app.DatasetSegmentationMenu = uimenu(app.Segmentation);
            app.DatasetSegmentationMenu.MenuSelectedFcn = createCallbackFcn(app, @DatasetSegmentationMenuSelected, true);
            app.DatasetSegmentationMenu.Text = 'Dataset Segmentation';
            app.ClearAnnotationsMenu = uimenu(app.Segmentation);
            app.ClearAnnotationsMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearAnnotationsMenuSelected, true);
            app.ClearAnnotationsMenu.Text = 'Clear Annotations';
            app.ClearSegmentationsMenu = uimenu(app.Segmentation);
            app.ClearSegmentationsMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearSegmentationsMenuSelected, true);
            app.ClearSegmentationsMenu.Text = 'Clear Segmentations';
            app.SavesegmentationstoUnclassifiedclassMenu = uimenu(app.Segmentation);
            app.SavesegmentationstoUnclassifiedclassMenu.MenuSelectedFcn = createCallbackFcn(app, @SavesegmentationstoUnclassifiedclassMenuSelected, true);
            app.SavesegmentationstoUnclassifiedclassMenu.Text = 'Save segmentations to ''Unclassified'' class';
            app.OptionsMenu_Segmentation = uimenu(app.Segmentation);
            app.OptionsMenu_Segmentation.MenuSelectedFcn = createCallbackFcn(app, @OptionsMenu_SegmentationSelected, true);
            app.OptionsMenu_Segmentation.Text = 'Segmentation – Training Options';
            app.Menu_Analysis = uimenu(app.figure1);
            app.Menu_Analysis.Text = 'Analysis';
            app.Menu_Analysis.Tag = 'Menu_Analysis';
            app.Menu_Analysis_ExpMeasurements = uimenu(app.Menu_Analysis);
            app.Menu_Analysis_ExpMeasurements.MenuSelectedFcn = createCallbackFcn(app, @Menu_Analysis_ExpMeasurements_Callback, true);
            app.Menu_Analysis_ExpMeasurements.Text = 'Export Measurements';
            app.Menu_Analysis_ExpMeasurements.Tag = 'Menu_Analysis_ExpMeasurements';
            app.Menu_Analysis_ExpSnapshots = uimenu(app.Menu_Analysis);
            app.Menu_Analysis_ExpSnapshots.MenuSelectedFcn = createCallbackFcn(app, @Menu_Analysis_ExpSnapshots_Callback, true);
            app.Menu_Analysis_ExpSnapshots.Text = 'Export Comet Snapshots';
            app.Menu_Analysis_ExpSnapshots.Tag = 'Menu_Analysis_ExpSnapshots';
            app.ClassificationMenu = uimenu(app.figure1);
            app.ClassificationMenu.Text = 'Classification';
            app.TrainClassifierMenu = uimenu(app.ClassificationMenu);
            app.TrainClassifierMenu.MenuSelectedFcn = createCallbackFcn(app, @TrainClassifierMenuSelected, true);
            app.TrainClassifierMenu.Text = 'Train Classifier';
            app.ExportClassifierMenu = uimenu(app.ClassificationMenu);
            app.ExportClassifierMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportClassifierMenuSelected, true);
            app.ExportClassifierMenu.Text = 'Export Classifier';
            app.PredictionMenu = uimenu(app.ClassificationMenu);
            app.PredictionMenu.MenuSelectedFcn = createCallbackFcn(app, @PredictionMenuSelected, true);
            app.PredictionMenu.Text = 'Prediction';
            app.ClassificationMenu_2 = uimenu(app.ClassificationMenu);
            app.ClassificationMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ClassificationMenu_2Selected, true);
            app.ClassificationMenu_2.Text = 'Classification';
            app.ClassificationTrainingOptionsMenu = uimenu(app.ClassificationMenu);
            app.ClassificationTrainingOptionsMenu.MenuSelectedFcn = createCallbackFcn(app, @ClassificationTrainingOptionsMenuSelected, true);
            app.ClassificationTrainingOptionsMenu.Text = 'Classification - Training Options';
            app.Menu_Help = uimenu(app.figure1);
            app.Menu_Help.Text = 'Help';
            app.Menu_Help.Tag = 'Menu_Help';
            app.Menu_Help_About = uimenu(app.Menu_Help);
            app.Menu_Help_About.MenuSelectedFcn = createCallbackFcn(app, @Menu_Help_About_Callback, true);
            app.Menu_Help_About.Text = 'About Us';
            app.Menu_Help_About.Tag = 'Menu_Help_About';
            app.DocumentationMenu = uimenu(app.Menu_Help);
            app.DocumentationMenu.MenuSelectedFcn = createCallbackFcn(app, @DocumentationMenuSelected, true);
            app.DocumentationMenu.Text = 'Documentation';
            app.but_Left = uibutton(app.figure1, 'push');
            app.but_Left.ButtonPushedFcn = createCallbackFcn(app, @but_Left_Callback, true);
            app.but_Left.Tag = 'but_Left';
            app.but_Left.Text = '<<';
            app.but_Right = uibutton(app.figure1, 'push');
            app.but_Right.ButtonPushedFcn = createCallbackFcn(app, @but_Right_Callback, true);
            app.but_Right.Tag = 'but_Right';
            app.but_Right.Text = '>>';
            app.text_Num = uilabel(app.figure1);
            app.text_Num.Tag = 'text_Num';
            app.text_Num.Text = ' Image number ';
            app.text_Name = uilabel(app.figure1);
            app.text_Name.Tag = 'text_Name';
            app.text_Name.Text = ' Image name';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Tag = 'uipanel1';
            app.text5 = uilabel(app.uipanel1);
            app.text5.Tag = 'text5';
            app.text5.Enable = 'off';
            app.text5.Text = 'CURRENT SELECTED COMET';
            app.but_CometAdd = uibutton(app.uipanel1, 'push');
            app.but_CometAdd.ButtonPushedFcn = createCallbackFcn(app, @but_CometAdd_Callback, true);
            app.but_CometAdd.Tag = 'but_CometAdd';
            app.but_CometAdd.Text = 'Add comet to class:';
            app.but_CometRemove = uibutton(app.uipanel1, 'push');
            app.but_CometRemove.ButtonPushedFcn = createCallbackFcn(app, @but_CometRemove_Callback, true);
            app.but_CometRemove.Tag = 'but_CometRemove';
            app.but_CometRemove.Text = 'Remove comet';
            app.pop_class = uidropdown(app.uipanel1);
            app.pop_class.Items = {'~No Class~'};
            app.pop_class.ValueChangedFcn = createCallbackFcn(app, @pop_class_Callback, true);
            app.pop_class.Tag = 'pop_class';
            app.pop_class.Value = '~No Class~';
            app.text6 = uilabel(app.uipanel1);
            app.text6.Tag = 'text6';
            app.text6.Enable = 'off';
            app.text6.Text = {'threshold'; '                       '};
            app.but_th_less = uibutton(app.uipanel1, 'push');
            app.but_th_less.ButtonPushedFcn = createCallbackFcn(app, @but_th_less_Callback, true);
            app.but_th_less.Tag = 'but_th_less';
            app.but_th_less.Text = '-';
            app.but_th_plus = uibutton(app.uipanel1, 'push');
            app.but_th_plus.ButtonPushedFcn = createCallbackFcn(app, @but_th_plus_Callback, true);
            app.but_th_plus.Tag = 'but_th_plus';
            app.but_th_plus.Text = '+';
            app.text7 = uilabel(app.uipanel1);
            app.text7.Tag = 'text7';
            app.text7.Enable = 'off';
            app.text7.Text = 'dilation';
            app.but_di_less = uibutton(app.uipanel1, 'push');
            app.but_di_less.ButtonPushedFcn = createCallbackFcn(app, @but_di_less_Callback, true);
            app.but_di_less.Tag = 'but_di_less';
            app.but_di_less.Text = '-';
            app.but_di_plus = uibutton(app.uipanel1, 'push');
            app.but_di_plus.ButtonPushedFcn = createCallbackFcn(app, @but_di_plus_Callback, true);
            app.but_di_plus.Tag = 'but_di_plus';
            app.but_di_plus.Text = '+';
            app.but_SelectComet = uibutton(app.uipanel1, 'push');
            app.but_SelectComet.ButtonPushedFcn = createCallbackFcn(app, @but_SelectComet_Callback, true);
            app.but_SelectComet.Tag = 'but_SelectComet';
            app.but_SelectComet.Text = 'Select a comet:';
            app.pop_tail = uidropdown(app.uipanel1);
            app.pop_tail.Items = {'without tail', 'without head', 'with tail and head'};
            app.pop_tail.ValueChangedFcn = createCallbackFcn(app, @pop_tail_Callback, true);
            app.pop_tail.Tag = 'pop_tail';
            app.pop_tail.Value = 'without tail';
            app.text8 = uilabel(app.uipanel1);
            app.text8.Tag = 'text8';
            app.text8.Enable = 'off';
            app.text8.Text = 'threshold modality:';
            app.pop_threshold = uidropdown(app.uipanel1);
            app.pop_threshold.Items = {'Otsu threshold', 'Triangle threshold', 'Average between Otsu and Triangle'};
            app.pop_threshold.ValueChangedFcn = createCallbackFcn(app, @pop_threshold_Callback, true);
            app.pop_threshold.Tag = 'pop_threshold';
            app.pop_threshold.Value = 'Otsu threshold';
            app.text_Threshold = uibutton(app.uipanel1, 'push');
            app.text_Threshold.ButtonPushedFcn = createCallbackFcn(app, @text_Threshold_Callback, true);
            app.text_Threshold.Tag = 'text_Threshold';
            app.text_Threshold.Text = '0';
            app.text_Dilation = uibutton(app.uipanel1, 'push');
            app.text_Dilation.ButtonPushedFcn = createCallbackFcn(app, @text_Dilation_Callback, true);
            app.text_Dilation.Tag = 'text_Dilation';
            app.text_Dilation.Text = '0';
            app.text11 = uilabel(app.uipanel1);
            app.text11.Tag = 'text11';
            app.text11.Enable = 'off';
            app.text11.Text = 'PARAMETERS FOR COMET SEGMENTATION';
            app.text12 = uilabel(app.uipanel1);
            app.text12.Tag = 'text12';
            app.text12.Enable = 'off';
            app.text12.Text = 'PARAMETERS FOR HEAD SEGMENTATION';
            app.text13 = uilabel(app.uipanel1);
            app.text13.Tag = 'text13';
            app.text13.Enable = 'off';
            app.text13.Text = {'threshold'; '                       '};
            app.text14 = uilabel(app.uipanel1);
            app.text14.Tag = 'text14';
            app.text14.Enable = 'off';
            app.text14.Text = {'dilation'; '                       '};
            app.but_thHead_less = uibutton(app.uipanel1, 'push');
            app.but_thHead_less.ButtonPushedFcn = createCallbackFcn(app, @but_thHead_less_Callback, true);
            app.but_thHead_less.Tag = 'but_thHead_less';
            app.but_thHead_less.Text = '-';
            app.but_thHead_plus = uibutton(app.uipanel1, 'push');
            app.but_thHead_plus.ButtonPushedFcn = createCallbackFcn(app, @but_thHead_plus_Callback, true);
            app.but_thHead_plus.Tag = 'but_thHead_plus';
            app.but_thHead_plus.Text = '+';
            app.text_ThresholdHead = uibutton(app.uipanel1, 'push');
            app.text_ThresholdHead.ButtonPushedFcn = createCallbackFcn(app, @text_ThresholdHead_Callback, true);
            app.text_ThresholdHead.Tag = 'text_ThresholdHead';
            app.text_ThresholdHead.Text = '0';
            app.but_diHead_less = uibutton(app.uipanel1, 'push');
            app.but_diHead_less.ButtonPushedFcn = createCallbackFcn(app, @but_diHead_less_Callback, true);
            app.but_diHead_less.Tag = 'but_diHead_less';
            app.but_diHead_less.Text = '-';
            app.but_diHead_plus = uibutton(app.uipanel1, 'push');
            app.but_diHead_plus.ButtonPushedFcn = createCallbackFcn(app, @but_diHead_plus_Callback, true);
            app.but_diHead_plus.Tag = 'but_diHead_plus';
            app.but_diHead_plus.Text = '+';
            app.text_DilationHead = uibutton(app.uipanel1, 'push');
            app.text_DilationHead.ButtonPushedFcn = createCallbackFcn(app, @text_DilationHead_Callback, true);
            app.text_DilationHead.Tag = 'text_DilationHead';
            app.text_DilationHead.Text = '0';
            app.text17 = uilabel(app.uipanel1);
            app.text17.Tag = 'text17';
            app.text17.Enable = 'off';
            app.text17.Text = 'ADD COMET AND SAVE RESULTS';
            app.check_CometFitFreehand = uicheckbox(app.uipanel1);
            app.check_CometFitFreehand.ValueChangedFcn = createCallbackFcn(app, @check_CometFitFreehand_Callback, true);
            app.check_CometFitFreehand.Tag = 'check_CometFitFreehand';
            app.check_CometFitFreehand.Text = 'comet fitting the freehand selection';
            app.text18 = uilabel(app.uipanel1);
            app.text18.Tag = 'text18';
            app.text18.Enable = 'off';
            app.text18.Text = 'threshold modality:';
            app.pop_thresholdHead = uidropdown(app.uipanel1);
            app.pop_thresholdHead.Items = {'Otsu threshold', 'Triangle threshold', 'Average between Otsu and Triangle'};
            app.pop_thresholdHead.ValueChangedFcn = createCallbackFcn(app, @pop_thresholdHead_Callback, true);
            app.pop_thresholdHead.Tag = 'pop_thresholdHead';
            app.pop_thresholdHead.Value = 'Otsu threshold';
            app.slider_comet_threshold = uislider(app.uipanel1);
            app.slider_comet_threshold.Limits = [0 1];
            app.slider_comet_threshold.MajorTicks = [];
            app.slider_comet_threshold.ValueChangedFcn = createCallbackFcn(app, @slider_comet_threshold_Callback, true);
            app.slider_comet_threshold.MinorTicks = [];
            app.slider_comet_threshold.Tag = 'slider_comet_threshold';
            app.slider_comet_threshold.Value = 0.5;
            app.slider_comet_Dilation = uislider(app.uipanel1);
            app.slider_comet_Dilation.Limits = [0 1];
            app.slider_comet_Dilation.MajorTicks = [];
            app.slider_comet_Dilation.ValueChangedFcn = createCallbackFcn(app, @slider_comet_Dilation_Callback, true);
            app.slider_comet_Dilation.MinorTicks = [];
            app.slider_comet_Dilation.Tag = 'slider_comet_Dilation';
            app.slider_head_threshold = uislider(app.uipanel1);
            app.slider_head_threshold.Limits = [0 1];
            app.slider_head_threshold.MajorTicks = [];
            app.slider_head_threshold.ValueChangedFcn = createCallbackFcn(app, @slider_head_threshold_Callback, true);
            app.slider_head_threshold.MinorTicks = [];
            app.slider_head_threshold.Tag = 'slider_head_threshold';
            app.slider_head_threshold.Value = 0.5;
            app.slider_head_Dilation = uislider(app.uipanel1);
            app.slider_head_Dilation.Limits = [0 1];
            app.slider_head_Dilation.MajorTicks = [];
            app.slider_head_Dilation.ValueChangedFcn = createCallbackFcn(app, @slider_head_Dilation_Callback, true);
            app.slider_head_Dilation.MinorTicks = [];
            app.slider_head_Dilation.Tag = 'slider_head_Dilation';
            app.ClassBrowserButton = uibutton(app.uipanel1, 'push');
            app.ClassBrowserButton.ButtonPushedFcn = createCallbackFcn(app, @ClassBrowserButtonPushed, true);
            app.ClassBrowserButton.Text = 'Class Browser';
            app.Class_Create_Button = uibutton(app.uipanel1, 'push');
            app.Class_Create_Button.ButtonPushedFcn = createCallbackFcn(app, @Class_Create_ButtonPushed, true);
            app.Class_Create_Button.Tag = 'CreateNewCLass';
            app.Class_Create_Button.Tooltip = {'Create New Class'};
            app.Class_Create_Button.Text = '+';
            app.Class_Remove_Button = uibutton(app.uipanel1, 'push');
            app.Class_Remove_Button.ButtonPushedFcn = createCallbackFcn(app, @Class_Remove_ButtonPushed, true);
            app.Class_Remove_Button.Tag = 'but_diHead_less';
            app.Class_Remove_Button.Tooltip = {'Remove Class'};
            app.Class_Remove_Button.Text = '-';
            app.scope = uiimage(app.uipanel1);
            app.but_ShowOriImg = uibutton(app.figure1, 'push');
            app.but_ShowOriImg.ButtonPushedFcn = createCallbackFcn(app, @but_ShowOriImg_Callback, true);
            app.but_ShowOriImg.Tag = 'but_ShowOriImg';
            app.but_ShowOriImg.Text = 'Show original image';
            app.Panel = uipanel(app.figure1);
            app.axes1 = uiaxes(app.Panel);
            app.axes1.XTick = [];
            app.axes1.XTickLabel = '';
            app.axes1.YTick = [];
            app.axes1.YTickLabel = '';
            app.axes1.Tag = 'axes1';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CometAnalyser_GUI_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)CometAnalyser_GUI_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end