classdef classNameInput < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ClassCreationUIFigure       matlab.ui.Figure
        ApplyButton                 matlab.ui.control.Button
        NewClassNameEditFieldLabel  matlab.ui.control.Label
        NewClassNameEditField       matlab.ui.control.EditField
        CancelButton                matlab.ui.control.Button
    end

    properties (Access = public)
        mainapp
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.mainapp = mainapp;
            uiwait(app.ClassCreationUIFigure)
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            forbiddenNames = [app.mainapp.pop_class.Items,'Unclassified'];
            toBeChecked = app.NewClassNameEditField.Value;
            text = 'Class Name';
            [bool,msg] = checkString(toBeChecked, forbiddenNames, [], [], [], 0, 'c', text);
            if bool
                if strcmp(app.mainapp.pop_class.Items{1},'~No Class~')
                    app.mainapp.pop_class.Items = {toBeChecked};
                else
                    app.mainapp.pop_class.Items = [app.mainapp.pop_class.Items, toBeChecked];
                    app.mainapp.pop_class.Value = toBeChecked;
                end
                app.mainapp.comet_handles.ClassCounter = app.mainapp.comet_handles.ClassCounter + 1;
                app.mainapp.comet_handles.Classes.(toBeChecked).Members = [];
                app.mainapp.comet_handles.Classes.(toBeChecked).num_el = 0;
                app.mainapp.comet_handles.Classes.(toBeChecked).ID = app.mainapp.comet_handles.ClassCounter;
                app.mainapp.classCreationSuccessor = 1;
                ClassCreationUIFigureCloseRequest(app)
            else
                warndlg(msg);
            end
        end

        % Close request function: ClassCreationUIFigure
        function ClassCreationUIFigureCloseRequest(app, event)
            uiresume(app.ClassCreationUIFigure)
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.mainapp.classCreationSuccessor = 0;
            ClassCreationUIFigureCloseRequest(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ClassCreationUIFigure = uifigure('Visible', 'off');
            app.ClassCreationUIFigure.Name = 'Class Creation';
            app.ClassCreationUIFigure.CloseRequestFcn = createCallbackFcn(app, @ClassCreationUIFigureCloseRequest, true);
            app.ApplyButton = uibutton(app.ClassCreationUIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.NewClassNameEditFieldLabel = uilabel(app.ClassCreationUIFigure);
            app.NewClassNameEditFieldLabel.Text = 'New Class Name:';
            app.NewClassNameEditField = uieditfield(app.ClassCreationUIFigure, 'text');
            app.CancelButton = uibutton(app.ClassCreationUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.ClassCreationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = classNameInput(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ClassCreationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ClassCreationUIFigure)
        end
    end
end