classdef segmentationOptions < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        segmentationOptionsUIFigure     matlab.ui.Figure
        SegmentationOptionsLabel        matlab.ui.control.Label
        PretrainedModelEditFieldLabel   matlab.ui.control.Label
        PretrainedModelEditField        matlab.ui.control.EditField
        SelectmodelButton               matlab.ui.control.Button
        InitialLearnRateEditFieldLabel  matlab.ui.control.Label
        InitialLearnRateEditField       matlab.ui.control.EditField
        LearnRateDropPeriodEditFieldLabel  matlab.ui.control.Label
        LearnRateDropPeriodEditField    matlab.ui.control.EditField
        LearnRateDropFactorEditFieldLabel  matlab.ui.control.Label
        LearnRateDropFactorEditField    matlab.ui.control.EditField
        MaxEpochEditFieldLabel          matlab.ui.control.Label
        MaxEpochEditField               matlab.ui.control.EditField
        MiniBatchSizeEditFieldLabel     matlab.ui.control.Label
        MiniBatchSizeEditField          matlab.ui.control.EditField
        ValidationPatienceEditFieldLabel  matlab.ui.control.Label
        ValidationPatienceEditField     matlab.ui.control.EditField
        PlotDropDownLabel               matlab.ui.control.Label
        PlotDropDown                    matlab.ui.control.DropDown
        ShuffleDataDropDownLabel        matlab.ui.control.Label
        ShuffleDataDropDown             matlab.ui.control.DropDown
        ExecutionEnvironmentDropDownLabel  matlab.ui.control.Label
        ExecutionEnvironmentDropDown    matlab.ui.control.DropDown
        L2RegularizationEditFieldLabel  matlab.ui.control.Label
        L2RegularizationEditField       matlab.ui.control.EditField
        MomentumEditFieldLabel          matlab.ui.control.Label
        MomentumEditField               matlab.ui.control.EditField
    end

    
    properties (Access = public)
        mainApp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.mainApp = mainApp;
            app.PretrainedModelEditField.Value = app.mainApp.comet_handles.segmentationOptions.modelPath;
            app.InitialLearnRateEditField.Value = app.mainApp.comet_handles.segmentationOptions.InitialLearnRate;
            app.LearnRateDropPeriodEditField.Value = app.mainApp.comet_handles.segmentationOptions.LearnRateDropPeriod;
            app.LearnRateDropFactorEditField.Value = app.mainApp.comet_handles.segmentationOptions.LearnRateDropFactor;
            app.MaxEpochEditField.Value = app.mainApp.comet_handles.segmentationOptions.MaxEpoch;
            app.MiniBatchSizeEditField.Value = app.mainApp.comet_handles.segmentationOptions.MiniBatchSize;
            app.ValidationPatienceEditField.Value = app.mainApp.comet_handles.segmentationOptions.ValidationPatience;
            app.PlotDropDown.Value = app.mainApp.comet_handles.segmentationOptions.Plot;
            app.ShuffleDataDropDown.Value = app.mainApp.comet_handles.segmentationOptions.ShuffleData;
            app.ExecutionEnvironmentDropDown.Value = app.mainApp.comet_handles.segmentationOptions.ExecutionEnvironment;
            app.L2RegularizationEditField.Value = app.mainApp.comet_handles.segmentationOptions.L2Regularization;
            app.MomentumEditField.Value = app.mainApp.comet_handles.segmentationOptions.Momentum;
        end

        % Button pushed function: SelectmodelButton
        function SelectmodelButtonPushed(app, event)
            [fileName, path] = uigetfile('Select a pre-trained model!');
            if ischar(path)
                app.PretrainedModelEditField.Value = fullfile(path,fileName);
            end
        end

        % Close request function: segmentationOptionsUIFigure
        function segmentationOptionsUIFigureCloseRequest(app, event)
            app.mainApp.comet_handles.segmentationOptions.modelPath = app.PretrainedModelEditField.Value;
            app.mainApp.comet_handles.segmentationOptions.InitialLearnRate = app.InitialLearnRateEditField.Value;
            app.mainApp.comet_handles.segmentationOptions.LearnRateDropPeriod = app.LearnRateDropPeriodEditField.Value;
            app.mainApp.comet_handles.segmentationOptions.LearnRateDropFactor = app.LearnRateDropFactorEditField.Value;
            app.mainApp.comet_handles.segmentationOptions.MaxEpoch = app.MaxEpochEditField.Value;
            app.mainApp.comet_handles.segmentationOptions.MiniBatchSize = app.MiniBatchSizeEditField.Value;
            app.mainApp.comet_handles.segmentationOptions.ValidationPatience = app.ValidationPatienceEditField.Value;
            app.mainApp.comet_handles.segmentationOptions.Plot = app.PlotDropDown.Value;
            app.mainApp.comet_handles.segmentationOptions.ShuffleData = app.ShuffleDataDropDown.Value;
            app.mainApp.comet_handles.segmentationOptions.ExecutionEnvironment = app.ExecutionEnvironmentDropDown.Value;
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.segmentationOptionsUIFigure = uifigure('Visible', 'off');
            app.segmentationOptionsUIFigure.Name = 'MATLAB App';
            app.segmentationOptionsUIFigure.CloseRequestFcn = createCallbackFcn(app, @segmentationOptionsUIFigureCloseRequest, true);
            app.SegmentationOptionsLabel = uilabel(app.segmentationOptionsUIFigure);
            app.SegmentationOptionsLabel.Text = 'Segmentation Options';
            app.PretrainedModelEditFieldLabel = uilabel(app.segmentationOptionsUIFigure);
            app.PretrainedModelEditFieldLabel.Text = 'Pre-trained Model';
            app.PretrainedModelEditField = uieditfield(app.segmentationOptionsUIFigure, 'text');
            app.PretrainedModelEditField.Value = 'Default';
            app.SelectmodelButton = uibutton(app.segmentationOptionsUIFigure, 'push');
            app.SelectmodelButton.ButtonPushedFcn = createCallbackFcn(app, @SelectmodelButtonPushed, true);
            app.SelectmodelButton.Text = 'Select model';
            app.InitialLearnRateEditFieldLabel = uilabel(app.segmentationOptionsUIFigure);
            app.InitialLearnRateEditFieldLabel.Text = 'Initial Learn Rate';
            app.InitialLearnRateEditField = uieditfield(app.segmentationOptionsUIFigure, 'text');
            app.InitialLearnRateEditField.Value = '0.003';
            app.LearnRateDropPeriodEditFieldLabel = uilabel(app.segmentationOptionsUIFigure);
            app.LearnRateDropPeriodEditFieldLabel.Text = 'Learn Rate Drop Period';
            app.LearnRateDropPeriodEditField = uieditfield(app.segmentationOptionsUIFigure, 'text');
            app.LearnRateDropPeriodEditField.Value = '8';
            app.LearnRateDropFactorEditFieldLabel = uilabel(app.segmentationOptionsUIFigure);
            app.LearnRateDropFactorEditFieldLabel.Text = 'Learn Rate Drop Factor';
            app.LearnRateDropFactorEditField = uieditfield(app.segmentationOptionsUIFigure, 'text');
            app.LearnRateDropFactorEditField.Value = '0.3';
            app.MaxEpochEditFieldLabel = uilabel(app.segmentationOptionsUIFigure);
            app.MaxEpochEditFieldLabel.Text = 'Max Epoch';
            app.MaxEpochEditField = uieditfield(app.segmentationOptionsUIFigure, 'text');
            app.MaxEpochEditField.Value = '50';
            app.MiniBatchSizeEditFieldLabel = uilabel(app.segmentationOptionsUIFigure);
            app.MiniBatchSizeEditFieldLabel.Text = 'Mini Batch Size';
            app.MiniBatchSizeEditField = uieditfield(app.segmentationOptionsUIFigure, 'text');
            app.MiniBatchSizeEditField.Value = '1';
            app.ValidationPatienceEditFieldLabel = uilabel(app.segmentationOptionsUIFigure);
            app.ValidationPatienceEditFieldLabel.Text = 'Validation Patience';
            app.ValidationPatienceEditField = uieditfield(app.segmentationOptionsUIFigure, 'text');
            app.ValidationPatienceEditField.Value = '10';
            app.PlotDropDownLabel = uilabel(app.segmentationOptionsUIFigure);
            app.PlotDropDownLabel.Text = 'Plot';
            app.PlotDropDown = uidropdown(app.segmentationOptionsUIFigure);
            app.PlotDropDown.Items = {'none', 'training-progress'};
            app.PlotDropDown.Value = 'none';
            app.ShuffleDataDropDownLabel = uilabel(app.segmentationOptionsUIFigure);
            app.ShuffleDataDropDownLabel.Text = 'Shuffle Data';
            app.ShuffleDataDropDown = uidropdown(app.segmentationOptionsUIFigure);
            app.ShuffleDataDropDown.Items = {'every-epoch', 'once', 'never'};
            app.ShuffleDataDropDown.Value = 'every-epoch';
            app.ExecutionEnvironmentDropDownLabel = uilabel(app.segmentationOptionsUIFigure);
            app.ExecutionEnvironmentDropDownLabel.Text = 'Execution Environment';
            app.ExecutionEnvironmentDropDown = uidropdown(app.segmentationOptionsUIFigure);
            app.ExecutionEnvironmentDropDown.Items = {'auto', 'cpu', 'gpu', 'multi-gpu', 'parallel', ''};
            app.ExecutionEnvironmentDropDown.Value = 'auto';
            app.L2RegularizationEditFieldLabel = uilabel(app.segmentationOptionsUIFigure);
            app.L2RegularizationEditFieldLabel.Text = 'L2Regularization';
            app.L2RegularizationEditField = uieditfield(app.segmentationOptionsUIFigure, 'text');
            app.L2RegularizationEditField.Value = '0.005';
            app.MomentumEditFieldLabel = uilabel(app.segmentationOptionsUIFigure);
            app.MomentumEditFieldLabel.Text = 'Momentum';
            app.MomentumEditField = uieditfield(app.segmentationOptionsUIFigure, 'text');
            app.MomentumEditField.Value = '0.9';
            % Show the figure after all components are created
            app.segmentationOptionsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = segmentationOptions(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.segmentationOptionsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.segmentationOptionsUIFigure)
        end
    end
end